classdef Machine_mian < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        Menu_1           matlab.ui.container.Menu
        Menu_11          matlab.ui.container.Menu
        Menu_12          matlab.ui.container.Menu
        Menu_121         matlab.ui.container.Menu
        Menu_122         matlab.ui.container.Menu
        Menu_13          matlab.ui.container.Menu
        Menu_131         matlab.ui.container.Menu
        Menu_132         matlab.ui.container.Menu
        Menu_133         matlab.ui.container.Menu
        matMenu          matlab.ui.container.Menu
        Menu_14          matlab.ui.container.Menu
        Menu_15          matlab.ui.container.Menu
        Menu_2           matlab.ui.container.Menu
        Menu_21          matlab.ui.container.Menu
        Menu_22          matlab.ui.container.Menu
        Menu_23          matlab.ui.container.Menu
        Menu_3           matlab.ui.container.Menu
        Menu_31          matlab.ui.container.Menu
        Menu_32          matlab.ui.container.Menu
        Menu_4           matlab.ui.container.Menu
        Menu_41          matlab.ui.container.Menu
        Menu_42          matlab.ui.container.Menu
        Menu_5           matlab.ui.container.Menu
        Menu_51          matlab.ui.container.Menu
        Menu_52          matlab.ui.container.Menu
        Menu_521         matlab.ui.container.Menu
        Menu_5211        matlab.ui.container.Menu
        Menu_522         matlab.ui.container.Menu
        Menu_5221        matlab.ui.container.Menu
        Menu_523         matlab.ui.container.Menu
        Menu_5231        matlab.ui.container.Menu
        Menu_5232        matlab.ui.container.Menu
        GridLayout       matlab.ui.container.GridLayout
        TabGroup         matlab.ui.container.TabGroup
        Tab_1            matlab.ui.container.Tab
        UIAxes_1         matlab.ui.control.UIAxes
        Tab_2            matlab.ui.container.Tab
        UIAxes_2         matlab.ui.control.UIAxes
        Tab_3            matlab.ui.container.Tab
        TabGroup2_1      matlab.ui.container.TabGroup
        Tab_31           matlab.ui.container.Tab
        UIAxes_31        matlab.ui.control.UIAxes
        Tab_32           matlab.ui.container.Tab
        UIAxes_32        matlab.ui.control.UIAxes
        Tab_33           matlab.ui.container.Tab
        UIAxes_33        matlab.ui.control.UIAxes
        Tab_4            matlab.ui.container.Tab
        TabGroup2_2      matlab.ui.container.TabGroup
        Tab_41           matlab.ui.container.Tab
        UIAxes_41        matlab.ui.control.UIAxes
        Tab_42           matlab.ui.container.Tab
        UIAxes_42        matlab.ui.control.UIAxes
        Panel            matlab.ui.container.Panel
        GridLayout2      matlab.ui.container.GridLayout
        EditField_in1    matlab.ui.control.NumericEditField
        EditField_in2    matlab.ui.control.NumericEditField
        EditField_in3    matlab.ui.control.NumericEditField
        EditField_in4    matlab.ui.control.NumericEditField
        EditField_in5    matlab.ui.control.NumericEditField
        EditField_in6    matlab.ui.control.NumericEditField
        dwBQLabel_in1    matlab.ui.control.Label
        dwBQLabel_in2    matlab.ui.control.Label
        dwBQLabel_in3    matlab.ui.control.Label
        dwBQLabel_in4    matlab.ui.control.Label
        dwBQLabel_in5    matlab.ui.control.Label
        dwBQLabel_in6    matlab.ui.control.Label
        BQLabel_in1      matlab.ui.control.Label
        BQLabel_in2      matlab.ui.control.Label
        BQLabel_in3      matlab.ui.control.Label
        BQLabel_in4      matlab.ui.control.Label
        BQLabel_in5      matlab.ui.control.Label
        BQLabel_in6      matlab.ui.control.Label
        BQLabel_in7      matlab.ui.control.Label
        EditField_in7    matlab.ui.control.NumericEditField
        dwBQLabel_in7    matlab.ui.control.Label
        Panel_2          matlab.ui.container.Panel
        GridLayout3      matlab.ui.container.GridLayout
        EditField_out1   matlab.ui.control.NumericEditField
        EditField_out2   matlab.ui.control.NumericEditField
        EditField_out3   matlab.ui.control.NumericEditField
        EditField_out4   matlab.ui.control.NumericEditField
        EditField_out5   matlab.ui.control.NumericEditField
        EditField_out6   matlab.ui.control.NumericEditField
        EditField_out8   matlab.ui.control.NumericEditField
        EditField_out9   matlab.ui.control.NumericEditField
        EditField_out11  matlab.ui.control.NumericEditField
        EditField_out12  matlab.ui.control.NumericEditField
        dwBQLabel_out1   matlab.ui.control.Label
        dwBQLabel_out4   matlab.ui.control.Label
        dwBQLabel_out2   matlab.ui.control.Label
        dwBQLabel_out3   matlab.ui.control.Label
        dwBQLabel_out5   matlab.ui.control.Label
        dwBQLabel_out6   matlab.ui.control.Label
        dwBQLabel_out8   matlab.ui.control.Label
        dwBQLabel_out9   matlab.ui.control.Label
        dwBQLabel_out11  matlab.ui.control.Label
        dwBQLabel_out12  matlab.ui.control.Label
        BQLabel_out1     matlab.ui.control.Label
        BQLabel_out2     matlab.ui.control.Label
        BQLabel_out3     matlab.ui.control.Label
        BQLabel_out4     matlab.ui.control.Label
        BQLabel_out5     matlab.ui.control.Label
        BQLabel_out6     matlab.ui.control.Label
        BQLabel_out7     matlab.ui.control.Label
        BQLabel_out8     matlab.ui.control.Label
        BQLabel_out9     matlab.ui.control.Label
        BQLabel_out10    matlab.ui.control.Label
        BQLabel_out11    matlab.ui.control.Label
        BQLabel_out12    matlab.ui.control.Label
        EditField_out7   matlab.ui.control.NumericEditField
        dwBQLabel_out7   matlab.ui.control.Label
        EditField_out10  matlab.ui.control.NumericEditField
        dwBQLabel_out10  matlab.ui.control.Label
        StartButton      matlab.ui.control.Button
        StopButton       matlab.ui.control.Button
        Label            matlab.ui.control.Label
        DropDown         matlab.ui.control.DropDown
        saveButton       matlab.ui.control.Button
        Switch           matlab.ui.control.Switch
        Button           matlab.ui.control.Button
        TipsLabel        matlab.ui.control.Label
        ContextMenu      matlab.ui.container.ContextMenu
        AMenu            matlab.ui.container.Menu
        BMenu            matlab.ui.container.Menu
        CMenu            matlab.ui.container.Menu
    end

    
    properties (Access = public)
         % 四杆机构参数
         l1 = 101.6;
         l2 = 254;
         l3 = 177.8;
         l4 = 304.8;
         omega1 = 250;
         alpha1 = 0;
         % 曲柄滑块参数
         %e = 0;
         %凸轮
         r0 = 40;    %基圆半径
         rr = 10;   %滚子半径
         h = 60;     %行程
         e = 15;     %偏距
         delta01 = 100;      %推程运动角-等加速等减速
         delta02 = 60;       %远休角
         delta03 = 90;       %回程运动角-余弦运运动
         
         %通用参数
         hd = pi/180;
         du = 180/pi;
         fir_theta = 70;
         
         % Description
         stop_sign = false;
         
         %子窗口
         drawpoint
         zhengni
        
         % 暂停继续
         click = 1; 
        zft = figure('Units',"normalized",'Position', ...
                [0.280469 0.553385 0.214453 0.251302], "Visible","off"); % Description
        save_Signal = 0 % Description
        grid_Sign = 1; % Description
    end
    
    properties (Access = private)
        currentx; 
        currenty; 
    end
    
    methods (Access = public)
        
        function [theta, omega, alpha] = crank_rocker(~, theta1, omega1, alpha1, l1, l2, l3, l4)
            %计算从动件的角位移
            L = sqrt(l4^2 + l1^2 - 2*l4*l1*cos(theta1));
            phi = asin((l1./L)*sin(theta1));
            beta = acos((-l2^2+l3^2+L^2)/(2*l3*L));
            if beta<0
                beta = beta * pi;
            end
            theta3 = pi-phi-beta;
            theta2 = asin((l3*sin(theta3)-l1*sin(theta1))/l2);
            theta = [theta2;theta3];
            
            %计算从动件的角速度
            A = [-l2*sin(theta2), l3*sin(theta3);
                l2*cos(theta2),-l3*cos(theta3)];
            B = [l1*sin(theta1);-l1*cos(theta1)];
            omega = A\(omega1*B);
            omega2 = omega(1);
            omega3 = omega(2);
            
            %计算从动件的角加速度
            A = [-l2*sin(theta2),l3*sin(theta3)
                l2*cos(theta2),-l3*cos(theta3)];
            At = [-omega2*l2*cos(theta2),omega3*l3*cos(theta3)
                -omega2*l2*sin(theta2),omega3*l3*sin(theta3)];
            B = [l1*sin(theta1);-l1*cos(theta1)];
            Bt = [omega1*l1*cos(theta1);omega1*l1*sin(theta1)];
            alpha = A\(-At * omega + alpha1 * B + omega1 * Bt);
        end
        
        function crank_rocker_main(app)
            
            theta2 = zeros(1,200);
            theta3 = zeros(1,200);
            omega2 = zeros(1,200);
            omega3 = zeros(1,200);
            alpha2 = zeros(1,200);
            alpha3 = zeros(1,200);
            
            %调用子函数crank_rocker计算铰链四杆机构位移，角速度，角加速度
            for n1 = 1:361
                theta1 = (n1 - 1) * app.hd;
                [theta,omega,alpha] = crank_rocker(app, theta1, app.omega1, app.alpha1,app.l1,app.l2,app.l3,app.l4);
                theta2(n1) = theta(1);
                theta3(n1) = theta(2);
                omega2(n1) = omega(1);
                omega3(n1) = omega(2);
                alpha2(n1) = alpha(1);
                alpha3(n1) = alpha(2);
            end
            
            %第一部分 四杆机构结构模型
            x(1) = 0;
            y(1) = 0;
            x(2) = app.l1*cos(app.fir_theta * app.hd);
            y(2) = app.l1*sin(app.fir_theta * app.hd);
            x(3) = app.l4+app.l3*cos(theta3(app.fir_theta+1));
            y(3) = app.l3*sin(theta3(app.fir_theta+1));
            x(4) = app.l4;
            y(4) = 0;
            x(5) = 0;
            y(5) = 0;
            plot(app.UIAxes_1, x, y);
            grid(app.UIAxes_1, "on");
            hold(app.UIAxes_1, "on");
            plot(app.UIAxes_1, [0 app.l4], [0 0], "LineWidth", 2, "Color", [0.6350 0.0780 0.1840]);
            plot(app.UIAxes_1, x(1), y(1), 'o');
            plot(app.UIAxes_1, x(2), y(2), 'o');
            plot(app.UIAxes_1, x(3), y(3), 'o');
            plot(app.UIAxes_1, x(4), y(4), 'o');
            title(app.UIAxes_1, '四杆机构');
            xlabel(app.UIAxes_1, 'mm');
            ylabel(app.UIAxes_1, 'mm');
            axis(app.UIAxes_1, [-50 350 -50 203]);
            
            %第三部分 四杆机构运动线图
            cla(app.UIAxes_31);%清除坐标系
            cla(app.UIAxes_32);%清除坐标系
            cla(app.UIAxes_33);%清除坐标系
            n1 = 1:361;
            plot(app.UIAxes_31, n1, theta2 * app.du, n1, theta3 * app.du, 'k');
            title(app.UIAxes_31, '角位移线图');
            xlabel(app.UIAxes_31, '曲柄转角 \theta_1/ \circ');
            ylabel(app.UIAxes_31, '角位移/ \circ');
            %axis(app.UIAxes_31, [0 360]);
            grid(app.UIAxes_31, "on");
            hold(app.UIAxes_31, "on");
            text(app.UIAxes_31, 140, 170, '\theta_3');
            text(app.UIAxes_31, 140, 30, '\theta_2');
            
            plot(app.UIAxes_32, n1, omega2, n1, omega3, 'k');
            title(app.UIAxes_32, '角速度线图');
            xlabel(app.UIAxes_32, '曲柄转角 \theta_1/ \circ');
            ylabel(app.UIAxes_32, '角速度/rad \cdots^{-1}');
            %axis(app.UIAxes_32, [0 360 -50 228]);
            grid(app.UIAxes_32, "on");
            hold(app.UIAxes_32, "on");
            text(app.UIAxes_32, 250, 130, '\omega_2');
            text(app.UIAxes_32, 130, 165, '\omega_3');
                
            plot(app.UIAxes_33, n1, alpha2, n1, alpha3, 'k');
            title(app.UIAxes_33, '角加速度线图');
            xlabel(app.UIAxes_33, '曲柄转角 \theta_1/ \circ');
            ylabel(app.UIAxes_33, '角速度/rad \cdots^{-2}');
            %axis(app.UIAxes_33, [0 360 -50 228]);
            grid(app.UIAxes_33, "on");
            hold(app.UIAxes_33, "on");
            text(app.UIAxes_33, 230, 2e4, '\alpha_2');
            text(app.UIAxes_33, 30, 7e4, '\alpha_3');
            
            %第二部分 四杆机构动态仿真
            for q = 1:100
                %m = moviein(40);
                %j = 0;
                skip = round((3*app.omega1)/250);
                for n1 = 1:skip:360
                    if app.stop_sign
                        break;
                    end
                    %j = j + 1;
                    cla(app.UIAxes_2);%清除坐标系
                    x(1) = 0;
                    y(1) = 0;
                    x(2) = app.l1*cos((n1 - 1)*app.hd);
                    y(2) = app.l1*sin((n1 - 1)*app.hd);
                    x(3) = app.l4+app.l3*cos(theta3(n1));
                    y(3) = app.l3*sin(theta3(n1));
                    x(4) = app.l4;
                    y(4) = 0;
                    x(5) = 0;
                    plot(app.UIAxes_2, x, y);
                    grid(app.UIAxes_2, "on");
                    hold(app.UIAxes_2, "on");
                    plot(app.UIAxes_2, [0 app.l4], [0 0], "LineWidth", 2, "Color", [0.6350 0.0780 0.1840]);
                    plot(app.UIAxes_2, x(1), y(1),'o');
                    plot(app.UIAxes_2, x(2), y(2),'o');
                    plot(app.UIAxes_2, x(3), y(3),'o');
                    plot(app.UIAxes_2, x(4), y(4),'o');
                    axis(app.UIAxes_2, [-200 500 -150 292]);
                    title(app.UIAxes_2, '铰链四杆机构');
                    xlabel(app.UIAxes_2, 'mm');
                    ylabel(app.UIAxes_2, 'mm');
                    %m(j) = getframe;
                    drawnow;
                    
                    %传递数据
                    try     
                        app.EditField_out1.Value = (n1 - 1) * app.hd;              %**********************************************bug
                        app.EditField_out2.Value = app.omega1;
                        app.EditField_out3.Value = app.alpha1;
                        app.EditField_out4.Value = theta2(n1);
                        app.EditField_out5.Value = omega2(n1);
                        app.EditField_out6.Value = alpha2(n1);
                        app.EditField_out8.Value = app.omega1 * app.l1;
                        app.EditField_out9.Value = (app.EditField_out2.Value * app.EditField_out2.Value) * app.l1;
                        app.EditField_out11.Value = omega3(n1) * app.l3;
                        app.EditField_out12.Value = sqrt((omega3(n1)*omega3(n1)*app.l3)*(omega3(n1)*omega3(n1)*app.l3) ...
                            + (alpha3(n1)*app.l3)*(alpha3(n1)*app.l3));
                        
                    catch
                        uialert(app.UIFigure, "出现数据溢出", "系统Bug");
                        delete(app);
                    end 
                end
                %disp("跳到那");
                if app.stop_sign
                    app.stop_sign = false;
                    break;
                end
            end
%             cla(app.UIAxes_31);%清除坐标系
%             cla(app.UIAxes_32);%清除坐标系
%             cla(app.UIAxes_33);%清除坐标系
%             cla(app.UIAxes_1);
%             cla(app.UIAxes_2);
        end


        function min = compare(~, l1, l2, l3, l4)
            L= zeros(1, 4);
            L(1) = l1;
            L(2) = l2;
            L(3) = l3;
            L(4) = l4;
            for i = 1:3
                for j = i+1:4
                    if L(i) > L(j)
                        temp = L(j);
                        L(j) = L(i);
                        L(i) = temp;
                    end
                end
            end
            disp(L(1));
            disp(L(4));
            if (L(1) + L(4)) < (L(2) + L(3))
                min = L(1);
            else
                min = 0;
                
            end
        end
        
        function slider_crank_main(app)
            theta1 = zeros(1,720);
            theta2 = zeros(1,720);
            s3 = zeros(1,720);
            omega2 = zeros(1,720);
            v3 = zeros(1,720);
            alpha2 = zeros(1,720);
            a3 = zeros(1,720);
            for n1 = 1:720
                theta1(n1) = (n1 - 1) * app.hd;
                [theta2(n1), s3(n1), omega2(n1), v3(n1), alpha2(n1), a3(n1)] = slider_crank(app, theta1(n1), ...
                    app.omega1, app.alpha1, app.l1, app.l2, app.e);
            end
            
            %第三部分 曲柄滑块机构运动线图
            %位移、速度、加速度和曲柄滑块机构图形输出
            %figure(1);
            %n1 = 1:720;
            %subplot(2,2,1);
            % [AX, ~, ~] = plotyy(theta1*du, theta2*du, theta1*du, s3);
            % set(get(AX(1),'ylabel'), 'String', '连杆角位移/ \circ');
            % set(get(AX(2),'ylabel'), 'String', '滑块位移/mm');
            cla(app.UIAxes_31);%清除坐标系
            cla(app.UIAxes_32);%清除坐标系
            cla(app.UIAxes_33);%清除坐标系
            x = theta1*app.du;
            y = theta2*app.du;
            yyaxis(app.UIAxes_31, "left");
            plot(app.UIAxes_31, x, y);
            ylabel(app.UIAxes_31, '连杆角位移/ \circ');
            z = s3;
            yyaxis(app.UIAxes_31, "right");
            plot(app.UIAxes_31, x, z);
            title(app.UIAxes_31, '位移线图');
            xlabel(app.UIAxes_31, '曲柄转角 \theta_1/ \circ');
            ylabel(app.UIAxes_31, '滑块位移/mm');
            grid(app.UIAxes_31, "on");
            
            %subplot(2,2,2);
            % [AX, ~, ~] = plotyy(theta1*du, omega2, theta1*du, v3);
            x = theta1*app.du;
            y = omega2;
            yyaxis(app.UIAxes_32, "left");
            plot(app.UIAxes_32, x, y);
            ylabel(app.UIAxes_32, '连杆角速度 /rad \cdots^{-1}');
            z = v3;
            yyaxis(app.UIAxes_32, "right");
            plot(app.UIAxes_32, x, z);
            title(app.UIAxes_32, '速度线图');
            xlabel(app.UIAxes_32, '曲柄转角 \theta_1/ \circ');
            ylabel(app.UIAxes_32, '滑块速度/mm \cdots^{-1}');
            %set(get(AX(2),'ylabel'), 'String', '滑块速度/mm \cdots^{-1}');
            grid(app.UIAxes_31, "on");
            
            %subplot(2,2,3);
            %[AX, H1, H2] = plotyy(theta1*du, alpha2, theta1*du, a3);
            x = theta1*app.du;
            y = alpha2;
            yyaxis(app.UIAxes_33, "left");
            plot(app.UIAxes_33, x, y);
            title(app.UIAxes_33, '加速度线图');
            xlabel(app.UIAxes_33, '曲柄转角 \theta_1/ \circ');
            ylabel(app.UIAxes_33, '连杆角加速度 /rad \cdots^{-2}');
            z = a3;
            yyaxis(app.UIAxes_33, "right");
            plot(app.UIAxes_33, x, z);
            ylabel(app.UIAxes_33, '滑块加速度/mm \cdots^{-2}');
            %set(get(AX(2), 'ylabel'), 'String', '滑块加速度/mm \cdots^{-2}');
            grid(app.UIAxes_33, "on");
            
            %第一部分 曲柄滑块机构结构模型
            x(1) = 0;
            y(1) = 0;
            x(2) = app.l1*cos(app.fir_theta*app.hd);
            y(2) = app.l1*sin(app.fir_theta*app.hd);
            x(3) = s3(app.fir_theta);
            y(3) = app.e;
            x(4) = s3(app.fir_theta);
            y(4) = 0;
            x(5) = 0;
            y(5) = 0;
            x(6) = x(3) - 40;
            y(6) = y(3) + 10;
            x(7) = x(3) + 40;
            y(7) = y(3) + 10;
            x(8) = x(3) + 40;
            y(8) = y(3) - 10;
            x(9) = x(3) - 40;
            y(9) = y(3) - 10;
            x(10) = x(3) - 40;
            y(10) = y(3) + 10;
            
            
            i = 1:5;
            plot(app.UIAxes_1, x(i), y(i));
            grid(app.UIAxes_1, "on");
            hold(app.UIAxes_1, "on");
            i = 6: 10;
            plot(app.UIAxes_1, x(i), y(i));
            title(app.UIAxes_1, '曲柄滑块机构');
            grid(app.UIAxes_1, "on");
            hold(app.UIAxes_1, "on");
            xlabel(app.UIAxes_1, 'mm');
            ylabel(app.UIAxes_1, 'mm');
            axis(app.UIAxes_1, [-50 400 -100 184]);
            plot(app.UIAxes_1, [0 450], [0 0], "LineWidth", 2, "Color", [0.6350 0.0780 0.1840]);
            plot(app.UIAxes_1, x(1), y(1), 'o');
            plot(app.UIAxes_1, x(2), y(2), 'o');
            plot(app.UIAxes_1, x(3), y(3), 'o');
            
            %disp("ddddddd");
            %第二部分 曲柄滑块机构动态仿真
            %figure(2)
            %j = 0;
            %m = moviein(20);
            for q = 1:100
                skip = round((3*app.omega1)/250);
                for n1 = 1:skip:360
                    if app.stop_sign
                        break;
                    end
                    %j = j + 1;
                    cla(app.UIAxes_2);%清除坐标系
                    %clf;
                    x(1) = 0;
                    y(1) = 0;
                    x(2) = app.l1*cos(n1*app.hd);
                    y(2) = app.l1*sin(n1*app.hd);
                    x(3) = s3(n1);
                    y(3) = app.e;
                    x(4) = (app.l1 + app.l2 + 50);
                    y(4) = 0;
                    x(5) = 0;
                    y(5) = 0;
                    x(6) = x(3) - 40;
                    y(6) = y(3) + 10;
                    x(7) = x(3) + 40;
                    y(7) = y(3) + 10;
                    x(8) = x(3) + 40;
                    y(8) = y(3) - 10;
                    x(9) = x(3) - 40;
                    y(9) = y(3) - 10;
                    x(10) = x(3) - 40;
                	y(10) = y(3) + 10;
                    
                    i = 1:3;
                    plot(app.UIAxes_2, x(i), y(i));
                    grid(app.UIAxes_2, "on");
                    hold(app.UIAxes_2, "on");
                    i = 4:5;
                    plot(app.UIAxes_2, x(i), y(i));
                    plot(app.UIAxes_2, [0 450], [0 0], "LineWidth", 2, "Color", [0.6350 0.0780 0.1840]);
                    i = 6:10;
                    plot(app.UIAxes_2, x(i), y(i));
                    plot(app.UIAxes_2, x(1), y(1), 'o');
                    plot(app.UIAxes_2, x(2), y(2), 'o');
                    plot(app.UIAxes_2, x(3), y(3), 'o');
                    
                    title(app.UIAxes_2, '曲柄滑块机构');
                    xlabel(app.UIAxes_2, 'mm');
                    ylabel(app.UIAxes_2, 'mm');
                    axis(app.UIAxes_2, [-150 450 -150 229]);
                    drawnow;
                    
                    %传递数据
                    app.EditField_out1.Value = (n1 - 1) * app.hd;           
                    app.EditField_out2.Value = app.omega1;
                    app.EditField_out3.Value = app.alpha1;
                    app.EditField_out4.Value = theta2(n1);
                    app.EditField_out5.Value = omega2(n1);
                    app.EditField_out6.Value = alpha2(n1);
                    app.EditField_out8.Value = app.omega1 * app.l1;
                    app.EditField_out9.Value = (app.EditField_out2.Value * app.EditField_out2.Value) * app.l1;
                    app.EditField_out11.Value = v3(n1);
                    app.EditField_out12.Value = a3(n1);
                    
                    %m(j) = getframe;
                end
                %movie(m);
                if app.stop_sign
                    app.stop_sign = false;
                    break;
                end
            end
%             cla(app.UIAxes_31);%清除坐标系
%             cla(app.UIAxes_32);%清除坐标系
%             cla(app.UIAxes_33);%清除坐标系
%             cla(app.UIAxes_1);
%             cla(app.UIAxes_2);
        end

        
        function Setfirst(app, name)
            %清零信息
            cla(app.UIAxes_31);%清除坐标系
            cla(app.UIAxes_32);%清除坐标系
            cla(app.UIAxes_33);%清除坐标系
            cla(app.UIAxes_1);
            cla(app.UIAxes_2);
%             clf(app.UIAxes_31);
%             clf(app.UIAxes_32);
%             clf(app.UIAxes_33);
%             clf(app.UIAxes_1);
%             clf(app.UIAxes_2);
            %app.stop_sign = true;
            app.EditField_out1.Value = 0;
            app.EditField_out2.Value = 0;
            app.EditField_out3.Value = 0;
            app.EditField_out4.Value = 0;
            app.EditField_out5.Value = 0;
            app.EditField_out6.Value = 0;
            app.EditField_out7.Value = 0;
            app.EditField_out8.Value = 0;
            app.EditField_out9.Value = 0;
            app.EditField_out10.Value = 0;
            app.EditField_out11.Value = 0;
            app.EditField_out12.Value = 0;
            
            if strcmpi(name, "                  四杆机构")
                app.TipsLabel.Text = "【Tips】：当前处于四杆机构分析板块，运动仿真未开启";
                %已知参数
                % 四杆机构标签名
                app.BQLabel_in1.Text = "曲柄AB长度";
                app.BQLabel_in1.Visible = 'on';
                app.BQLabel_in2.Text = "连杆BC长度";
                app.BQLabel_in2.Visible = 'on';
                app.BQLabel_in3.Text = "连架杆CD长度";
                app.BQLabel_in3.Visible = 'on';
                app.BQLabel_in4.Text = "机架AD长度";
                app.BQLabel_in4.Visible = 'on';
                app.BQLabel_in5.Text = "曲柄转速ω1";
                app.BQLabel_in5.Visible = 'on';
                app.BQLabel_in6.Text = "曲柄转动加速度a";
                app.BQLabel_in6.Visible = 'on';
                app.BQLabel_in7.Text = "曲柄起角Φ";
                app.BQLabel_in7.Visible = 'on';
                % 四杆机构初始值
                app.l1 = 101.6;
                app.l2 = 254;
                app.l3 = 177.8;
                app.l4 = 304.8;
                app.omega1 = 250;
                app.alpha1 = 0;
                app.fir_theta = 70;
                app.EditField_in1.Value = app.l1;
                app.EditField_in2.Value = app.l2;
                app.EditField_in3.Value = app.l3;
                app.EditField_in4.Value = app.l4;
                app.EditField_in5.Value = app.omega1;
                app.EditField_in6.Value = app.alpha1;
                app.EditField_in7.Value = app.fir_theta;
                % 四杆机构数值可见性
                app.EditField_in1.Visible = 'on';
                app.EditField_in2.Visible = 'on';
                app.EditField_in3.Visible = 'on';
                app.EditField_in4.Visible = 'on';
                app.EditField_in5.Visible = 'on';
                app.EditField_in6.Visible = 'on';
                app.EditField_in7.Visible = 'on';
                % 四杆机构单位
                app.dwBQLabel_in1.Text = "mm";
                app.dwBQLabel_in2.Text = "mm";
                app.dwBQLabel_in3.Text = "mm";
                app.dwBQLabel_in4.Text = "mm";
                app.dwBQLabel_in5.Text = "rad/s";
                app.dwBQLabel_in6.Text = "rad/s²";
                app.dwBQLabel_in7.Text = "°";
                % 四杆机构单位可见性
                app.dwBQLabel_in1.Visible = 'on';
                app.dwBQLabel_in2.Visible = 'on';
                app.dwBQLabel_in3.Visible = 'on';
                app.dwBQLabel_in4.Visible = 'on';
                app.dwBQLabel_in5.Visible = 'on';
                app.dwBQLabel_in6.Visible = 'on';
                app.dwBQLabel_in7.Visible = 'on';
                
                %坐标区结构模型
                % 四杆机构结构模型
                cla(app.UIAxes_1);%清除坐标系
                theta2 = zeros(1,200);
                theta3 = zeros(1,200);
                omega2 = zeros(1,200);
                omega3 = zeros(1,200);
                alpha2 = zeros(1,200);
                alpha3 = zeros(1,200);
                
                %调用子函数crank_rocker计算铰链四杆机构位移，角速度，角加速度
                for n1 = 1:361
                    theta1 = (n1 - 1) * app.hd;
                    [theta,omega,alpha] = crank_rocker(app, theta1, app.omega1, app.alpha1,app.l1,app.l2,app.l3,app.l4);
                    theta2(n1) = theta(1);
                    theta3(n1) = theta(2);
                    omega2(n1) = omega(1);
                    omega3(n1) = omega(2);
                    alpha2(n1) = alpha(1);
                    alpha3(n1) = alpha(2);
                end
                x(1) = 0;
                y(1) = 0;
                x(2) = app.l1*cos(app.fir_theta * app.hd);
                y(2) = app.l1*sin(app.fir_theta * app.hd);
                x(3) = app.l4+app.l3*cos(theta3(app.fir_theta+1));
                y(3) = app.l3*sin(theta3(app.fir_theta+1));
                x(4) = app.l4;
                y(4) = 0;
                x(5) = 0;
                y(5) = 0;
                
                %输出mat
                app.currentx = x;
                app.currenty = y;
                
                plot(app.UIAxes_1, x, y);
                grid(app.UIAxes_1, "on");
                hold(app.UIAxes_1, "on");
                plot(app.UIAxes_1, [0 app.l4], [0 0], "LineWidth", 2, "Color", [0.6350 0.0780 0.1840]);
                plot(app.UIAxes_1, x(1), y(1), 'o');
                plot(app.UIAxes_1, x(2), y(2), 'o');
                plot(app.UIAxes_1, x(3), y(3), 'o');
                plot(app.UIAxes_1, x(4), y(4), 'o');
                title(app.UIAxes_1, '四杆机构');
                xlabel(app.UIAxes_1, 'mm');
                ylabel(app.UIAxes_1, 'mm');
                axis(app.UIAxes_1, [-50 350 -50 203]);
                
                % 输出参数
                % 输出参数标签
                app.BQLabel_out1.Text = "连杆角位移";
                app.BQLabel_out2.Text = "连杆角速度";
                app.BQLabel_out3.Text = "连杆角加速度";
                app.BQLabel_out4.Text = "连架杆角位移";
                app.BQLabel_out5.Text = "连架杆角速度";
                app.BQLabel_out6.Text = "连架杆角加速度";
                app.BQLabel_out7.Text = "B点：";
                app.BQLabel_out8.Text = "B点速度";
                app.BQLabel_out9.Text = "B点加速度";
                app.BQLabel_out10.Text = "C点：";
                app.BQLabel_out11.Text = "C点速度";
                app.BQLabel_out12.Text = "C点加速度";
                app.BQLabel_out1.Visible = 'on';
                app.BQLabel_out2.Visible = 'on';
                app.BQLabel_out3.Visible = 'on';
                app.BQLabel_out4.Visible = 'on';
                app.BQLabel_out5.Visible = 'on';
                app.BQLabel_out6.Visible = 'on';
                app.BQLabel_out7.Visible = 'on';
                app.BQLabel_out8.Visible = 'on';
                app.BQLabel_out9.Visible = 'on';
                app.BQLabel_out10.Visible = 'on';
                app.BQLabel_out11.Visible = 'on';
                app.BQLabel_out12.Visible = 'on';
                %输出框
%                 app.EditField_out1.Value = 0;
%                 app.EditField_out2.Value = 0;
%                 app.EditField_out3.Value = 0;
%                 app.EditField_out4.Value = 0;
%                 app.EditField_out5.Value = 0;
%                 app.EditField_out6.Value = 0;
%                 app.EditField_out7.Value = 0;
%                 app.EditField_out8.Value = 0;
%                 app.EditField_out9.Value = 0;
%                 app.EditField_out10.Value = 0;
%                 app.EditField_out11.Value = 0;
%                 app.EditField_out12.Value = 0;
                app.EditField_out1.Visible = 'on';
                app.EditField_out2.Visible = 'on';
                app.EditField_out3.Visible = 'on';
                app.EditField_out4.Visible = 'on';
                app.EditField_out5.Visible = 'on';
                app.EditField_out6.Visible = 'on';
                app.EditField_out7.Visible = 'off';
                app.EditField_out8.Visible = 'on';
                app.EditField_out9.Visible = 'on';
                app.EditField_out10.Visible = 'off';
                app.EditField_out11.Visible = 'on';
                app.EditField_out12.Visible = 'on';
                %输出单位
                app.dwBQLabel_out1.Text = "rad";
                app.dwBQLabel_out2.Text = "rad/s";
                app.dwBQLabel_out3.Text = "rad/s²";
                app.dwBQLabel_out4.Text = "rad";
                app.dwBQLabel_out5.Text = "rad/s";
                app.dwBQLabel_out6.Text = "rad/s²";
                %app.dwBQLabel_out7.Text = "XXXXX";
                app.dwBQLabel_out8.Text = "mm/s";
                app.dwBQLabel_out9.Text = "mm/s²";
                %app.dwBQLabel_out10.Text = "XXXXX";
                app.dwBQLabel_out11.Text = "mm/s";
                app.dwBQLabel_out12.Text = "mm/s²";
                app.dwBQLabel_out1.Visible = 'on';
                app.dwBQLabel_out2.Visible = 'on';
                app.dwBQLabel_out3.Visible = 'on';
                app.dwBQLabel_out4.Visible = 'on';
                app.dwBQLabel_out5.Visible = 'on';
                app.dwBQLabel_out6.Visible = 'on';
                app.dwBQLabel_out7.Visible = 'off';
                app.dwBQLabel_out8.Visible = 'on';
                app.dwBQLabel_out9.Visible = 'on';
                app.dwBQLabel_out10.Visible = 'off';
                app.dwBQLabel_out11.Visible = 'on';
                app.dwBQLabel_out12.Visible = 'on';


            elseif strcmpi(name, "              曲柄滑块机构")
                app.TipsLabel.Text = "【Tips】：当前处于曲柄摇杆机构分析板块，运动仿真未开启";
                %已知参数
                % 曲柄摇杆机构标签名
                app.BQLabel_in1.Text = "曲柄AB长度";
                app.BQLabel_in1.Visible = 'on';
                app.BQLabel_in2.Text = "连杆BC长度";
                app.BQLabel_in2.Visible = 'on';
                app.BQLabel_in3.Text = "偏距";
                app.BQLabel_in3.Visible = 'on';
                app.BQLabel_in4.Text = "曲柄转速ω1";
                app.BQLabel_in4.Visible = 'on';
                app.BQLabel_in5.Text = "曲柄转动加速度a";
                app.BQLabel_in5.Visible = 'on';
                app.BQLabel_in6.Text = "曲柄起角Φ";
                app.BQLabel_in6.Visible = 'on';
                app.BQLabel_in7.Text = "XXXXX";
                app.BQLabel_in7.Visible = 'off';
                % 曲柄摇杆机构初始值
                app.l1 = 100;
                app.l2 = 300;
                app.e = 0;
                app.omega1 = 250;
                app.alpha1 = 0;
                app.fir_theta = 70;
                app.EditField_in1.Value = app.l1;
                app.EditField_in2.Value = app.l2;
                app.EditField_in3.Value = app.e;
                app.EditField_in4.Value = app.omega1;
                app.EditField_in5.Value = app.alpha1;
                app.EditField_in6.Value = app.fir_theta;
                %app.EditField_in7.Value = XXXXX;
                % 曲柄摇杆机构数值可见性
                app.EditField_in1.Visible = 'on';
                app.EditField_in2.Visible = 'on';
                app.EditField_in3.Visible = 'on';
                app.EditField_in4.Visible = 'on';
                app.EditField_in5.Visible = 'on';
                app.EditField_in6.Visible = 'on';
                app.EditField_in7.Visible = 'off';
                % 曲柄摇杆机构单位
                app.dwBQLabel_in1.Text = "mm";
                app.dwBQLabel_in2.Text = "mm";
                app.dwBQLabel_in3.Text = "mm";
                app.dwBQLabel_in4.Text = "rad/s";
                app.dwBQLabel_in5.Text = "rad/s²";
                app.dwBQLabel_in6.Text = "°";
                app.dwBQLabel_in7.Text = "XXXXX";
                % 曲柄摇杆机构单位可见性
                app.dwBQLabel_in1.Visible = 'on';
                app.dwBQLabel_in2.Visible = 'on';
                app.dwBQLabel_in3.Visible = 'on';
                app.dwBQLabel_in4.Visible = 'on';
                app.dwBQLabel_in5.Visible = 'on';
                app.dwBQLabel_in6.Visible = 'on';
                app.dwBQLabel_in7.Visible = 'off';
                
                %坐标区结构模型
                cla(app.UIAxes_1);%清除坐标系
                theta1 = zeros(1,720);
                theta2 = zeros(1,720);
                s3 = zeros(1,720);
                omega2 = zeros(1,720);
                v3 = zeros(1,720);
                alpha2 = zeros(1,720);
                a3 = zeros(1,720);
                for n1 = 1:720
                    theta1(n1) = (n1 - 1) * app.hd;
                    [theta2(n1), s3(n1), omega2(n1), v3(n1), alpha2(n1), a3(n1)] = slider_crank(app, theta1(n1), app.omega1, app.alpha1, app.l1, app.l2, app.e);
                end
                x(1) = 0;
                y(1) = 0;
                x(2) = app.l1*cos(70*app.hd);
                y(2) = app.l1*sin(70*app.hd);
                x(3) = s3(70);
                y(3) = app.e;
                x(4) = s3(70);
                y(4) = 0;
                x(5) = 0;
                y(5) = 0;
                x(6) = x(3) - 40;
                y(6) = y(3) + 10;
                x(7) = x(3) + 40;
                y(7) = y(3) + 10;
                x(8) = x(3) + 40;
                y(8) = y(3) - 10;
                x(9) = x(3) - 40;
                y(9) = y(3) - 10;
                x(10) = x(3) - 40;
                y(10) = y(3) + 10;
                i = 1:5;
                plot(app.UIAxes_1, x(i), y(i));
                grid(app.UIAxes_1, "on");
                hold(app.UIAxes_1, "on");
                plot(app.UIAxes_1, [0 400], [0 0], "LineWidth", 2, "Color", [0.6350 0.0780 0.1840]);
                i = 6: 10;
                plot(app.UIAxes_1, x(i), y(i));
                title(app.UIAxes_1, '曲柄滑块机构');
                grid(app.UIAxes_1, "on");
                hold(app.UIAxes_1, "on");
                xlabel(app.UIAxes_1, 'mm');
                ylabel(app.UIAxes_1, 'mm');
                axis(app.UIAxes_1, [-50 400 -100 184]);
                plot(app.UIAxes_1, x(1), y(1), 'o');
                plot(app.UIAxes_1, x(2), y(2), 'o');
                plot(app.UIAxes_1, x(3), y(3), 'o');

                % 输出参数
                % 输出参数标签
                app.BQLabel_out1.Text = "连杆角位移";
                app.BQLabel_out2.Text = "连杆角速度";
                app.BQLabel_out3.Text = "连杆角加速度";
                app.BQLabel_out4.Text = "连架杆角位移";
                app.BQLabel_out5.Text = "连架杆角速度";
                app.BQLabel_out6.Text = "连架杆角加速度";
                app.BQLabel_out7.Text = "B点：";
                app.BQLabel_out8.Text = "B点速度";
                app.BQLabel_out9.Text = "B点加速度";
                app.BQLabel_out10.Text = "C点：";
                app.BQLabel_out11.Text = "C点速度";
                app.BQLabel_out12.Text = "C点加速度";
                app.BQLabel_out1.Visible = 'on';
                app.BQLabel_out2.Visible = 'on';
                app.BQLabel_out3.Visible = 'on';
                app.BQLabel_out4.Visible = 'on';
                app.BQLabel_out5.Visible = 'on';
                app.BQLabel_out6.Visible = 'on';
                app.BQLabel_out7.Visible = 'on';
                app.BQLabel_out8.Visible = 'on';
                app.BQLabel_out9.Visible = 'on';
                app.BQLabel_out10.Visible = 'on';
                app.BQLabel_out11.Visible = 'on';
                app.BQLabel_out12.Visible = 'on';
                %输出框
%                 app.EditField_out1.Value = 0;
%                 app.EditField_out2.Value = 0;
%                 app.EditField_out3.Value = 0;
%                 app.EditField_out4.Value = 0;
%                 app.EditField_out5.Value = 0;
%                 app.EditField_out6.Value = 0;
%                 app.EditField_out7.Value = 0;
%                 app.EditField_out8.Value = 0;
%                 app.EditField_out9.Value = 0;
%                 app.EditField_out10.Value = 0;
%                 app.EditField_out11.Value = 0;
%                 app.EditField_out12.Value = 0;
                app.EditField_out1.Visible = 'on';
                app.EditField_out2.Visible = 'on';
                app.EditField_out3.Visible = 'on';
                app.EditField_out4.Visible = 'on';
                app.EditField_out5.Visible = 'on';
                app.EditField_out6.Visible = 'on';
                app.EditField_out7.Visible = 'off';
                app.EditField_out8.Visible = 'on';
                app.EditField_out9.Visible = 'on';
                app.EditField_out10.Visible = 'off';
                app.EditField_out11.Visible = 'on';
                app.EditField_out12.Visible = 'on';
                %输出单位
                app.dwBQLabel_out1.Text = "rad";
                app.dwBQLabel_out2.Text = "rad/s";
                app.dwBQLabel_out3.Text = "rad/s²";
                app.dwBQLabel_out4.Text = "rad";
                app.dwBQLabel_out5.Text = "rad/s";
                app.dwBQLabel_out6.Text = "rad/s²";
                %app.dwBQLabel_out7.Text = "XXXXX";
                app.dwBQLabel_out8.Text = "mm/s";
                app.dwBQLabel_out9.Text = "mm/s²";
                %app.dwBQLabel_out10.Text = "XXXXX";
                app.dwBQLabel_out11.Text = "mm/s";
                app.dwBQLabel_out12.Text = "mm/s²";
                app.dwBQLabel_out1.Visible = 'on';
                app.dwBQLabel_out2.Visible = 'on';
                app.dwBQLabel_out3.Visible = 'on';
                app.dwBQLabel_out4.Visible = 'on';
                app.dwBQLabel_out5.Visible = 'on';
                app.dwBQLabel_out6.Visible = 'on';
                app.dwBQLabel_out7.Visible = 'off';
                app.dwBQLabel_out8.Visible = 'on';
                app.dwBQLabel_out9.Visible = 'on';
                app.dwBQLabel_out10.Visible = 'off';
                app.dwBQLabel_out11.Visible = 'on';
                app.dwBQLabel_out12.Visible = 'on';


            elseif strcmpi(name, '                  凸轮机构')
                app.TipsLabel.Text = "【Tips】：当前处于凸轮机构分析板块，运动仿真未开启";
                % 已知参数
                % 凸轮机构标签名
                app.BQLabel_in1.Text = "基圆半径";
                app.BQLabel_in1.Visible = 'on';
                app.BQLabel_in2.Text = "滚子半径";
                app.BQLabel_in2.Visible = 'on';
                app.BQLabel_in3.Text = "行程";
                app.BQLabel_in3.Visible = 'on';
                app.BQLabel_in4.Text = "偏距";
                app.BQLabel_in4.Visible = 'on';
                app.BQLabel_in5.Text = "推程运动角";
                app.BQLabel_in5.Visible = 'on';
                app.BQLabel_in6.Text = "远休角";
                app.BQLabel_in6.Visible = 'on';
                app.BQLabel_in7.Text = "回程运动角";
                app.BQLabel_in7.Visible = 'on';
                
                % 凸轮机构初始值
                app.r0 = 40;
                app.rr = 10;
                app.e = 15;
                app.h = 60;
                app.delta01 = 100;
                app.delta02 = 60;
                app.delta03 = 90;
                app.EditField_in1.Value = app.r0;
                app.EditField_in2.Value = app.rr;
                app.EditField_in3.Value = app.h;
                app.EditField_in4.Value = app.e;
                app.EditField_in5.Value = app.delta01;
                app.EditField_in6.Value = app.delta02;
                app.EditField_in7.Value = app.delta03;
                % 凸轮机构数值可见性
                app.EditField_in1.Visible = 'on';
                app.EditField_in2.Visible = 'on';
                app.EditField_in3.Visible = 'on';
                app.EditField_in4.Visible = 'on';
                app.EditField_in5.Visible = 'on';
                app.EditField_in6.Visible = 'on';
                app.EditField_in7.Visible = 'on';
                % 凸轮机构单位
                app.dwBQLabel_in1.Text = "mm";
                app.dwBQLabel_in2.Text = "mm";
                app.dwBQLabel_in3.Text = "mm";
                app.dwBQLabel_in4.Text = "mm";
                app.dwBQLabel_in5.Text = "°";
                app.dwBQLabel_in6.Text = "°";
                app.dwBQLabel_in7.Text = "°";
                % 曲柄摇杆机构单位可见性
                app.dwBQLabel_in1.Visible = 'on';
                app.dwBQLabel_in2.Visible = 'on';
                app.dwBQLabel_in3.Visible = 'on';
                app.dwBQLabel_in4.Visible = 'on';
                app.dwBQLabel_in5.Visible = 'on';
                app.dwBQLabel_in6.Visible = 'on';
                app.dwBQLabel_in7.Visible = 'on';
                
                %坐标区结构模型
                %凸轮曲线设计
                s = zeros(1,360);
                xx = zeros(1,360);
                yy = zeros(1,360);
                dx = zeros(1,360);
                dy = zeros(1,360);
                xp = zeros(1,360);
                yp = zeros(1,360);
%                 ss = zeros(1,360);
%                 delta = zeros(1,360);
                
                se = sqrt(app.r0^2 - app.e^2);
                nn1 = app.delta01 + app.delta02;
                nn3 = app.delta01 + app.delta02 + app.delta03;
                
                n = 360;
                ds = zeros(1,361);
                for i = 1:n
                    %计算推杆运动规律
                    if i <= app.delta01/2
                        s(i) = 2*app.h*i^2/app.delta01^2;
                        ds(i) = 4*app.h*i*app.hd/(app.delta01*app.hd)^2;
                        ds = ds(i);
                    elseif i > app.delta01/2 && i <= app.delta01
                        s(i) = app.h - 2*app.h*(app.delta01 - i)^2/app.delta01^2;
                        ds(i) = 4*app.h*(app.delta01 - i)*app.hd/(app.delta01*app.hd)^2;
                        ds = ds(i);
                    elseif i > app.delta01 && i <= nn1
                        s(i) = app.h;
                        ds = 0;
                    elseif i > nn1 && i <= nn3
                        k = i - nn1;
                        s(i) = 0.5*app.h*(1 + cos(pi*k/app.delta03));
                        ds(i) = -0.5*pi*app.h*sin(pi*k/app.delta03)/(app.delta03*app.hd)^2;
                        ds = ds(i);
                    elseif i > nn3 && i <= n
                        s(i) = 0;
                        ds = 0;
                    end
                    
                    %计算凸轮轨迹曲线
                    xx(i) = (se + s(i))*sin(i*app.hd) + app.e*cos(i*app.hd);
                    yy(i) = (se + s(i))*cos(i*app.hd) - app.e*sin(i*app.hd);
                    dx(i) = (ds - app.e)*sin(i*app.hd) + (se + s(i))*cos(i*app.hd);
                    dy(i) = (ds - app.e)*cos(i*app.hd) - (se + s(i))*sin(i*app.hd);
                    xp(i) = xx(i) + app.rr*dy(i)/sqrt(dx(i)^2 + dy(i)^2);
                    yp(i) = yy(i) - app.rr*dx(i)/sqrt(dx(i)^2 + dy(i)^2);
                end
                
                %输出凸轮轮廓曲线
                %figure(1);
                hold(app.UIAxes_1, "on");
                grid(app.UIAxes_1, "on");
                axis(app.UIAxes_1, "equal");
                axis(app.UIAxes_1, [-(app.r0 + app.h - 30) (app.r0 + app.h + 10) -(app.r0 + app.h + 10) (app.r0 + app.rr + 10)]);
                text(app.UIAxes_1, app.r0 + app.h + 3, 4, 'X');
                text(app.UIAxes_1, 3, app.r0 + app.rr + 3, 'Y');
                text(app.UIAxes_1, -6, 4, 'O');
                title(app.UIAxes_1, '偏置直动滚子推杆盘形凸轮设计');
                xlabel(app.UIAxes_1, 'x/mm');
                ylabel(app.UIAxes_1, 'y/mm');
                plot(app.UIAxes_1, [-(app.r0 + app.h - 40) (app.r0 + app.h)], [0, 0], 'k');
                plot(app.UIAxes_1, [0,0], [-(app.r0 + app.h) (app.r0 + app.rr)], 'k');
                plot(app.UIAxes_1, xx, yy, 'r-');
                ct = linspace(0, 2*pi);
                plot(app.UIAxes_1, app.r0 * cos(ct), app.r0*sin(ct), 'g');
                plot(app.UIAxes_1, app.e*cos(ct), app.e*sin(ct), 'c-');
                plot(app.UIAxes_1, app.e + app.rr*cos(ct), se + app.rr*sin(ct),'k');
                plot(app.UIAxes_1, app.e, se, 'o');
                plot(app.UIAxes_1, [app.e app.e], [se se + 30], 'k');
                plot(app.UIAxes_1, xp, yp, 'b');
                
                % 输出参数
                % 输出参数标签
                app.BQLabel_out1.Text = "连杆角位移";
                app.BQLabel_out2.Text = "连杆角速度";
                app.BQLabel_out3.Text = "连杆角加速度";
                app.BQLabel_out4.Text = "连架杆角位移";
                app.BQLabel_out5.Text = "连架杆角速度";
                app.BQLabel_out6.Text = "连架杆角加速度";
                app.BQLabel_out7.Text = "B点：";
                app.BQLabel_out8.Text = "B点速度";
                app.BQLabel_out9.Text = "B点加速度";
                app.BQLabel_out10.Text = "C点：";
                app.BQLabel_out11.Text = "C点速度";
                app.BQLabel_out12.Text = "C点加速度";
                app.BQLabel_out1.Visible = 'off';
                app.BQLabel_out2.Visible = 'off';
                app.BQLabel_out3.Visible = 'off';
                app.BQLabel_out4.Visible = 'off';
                app.BQLabel_out5.Visible = 'off';
                app.BQLabel_out6.Visible = 'off';
                app.BQLabel_out7.Visible = 'off';
                app.BQLabel_out8.Visible = 'off';
                app.BQLabel_out9.Visible = 'off';
                app.BQLabel_out10.Visible = 'off';
                app.BQLabel_out11.Visible = 'off';
                app.BQLabel_out12.Visible = 'off';
                %输出框
                %                 app.EditField_out1.Value = 0;
                %                 app.EditField_out2.Value = 0;
                %                 app.EditField_out3.Value = 0;
                %                 app.EditField_out4.Value = 0;
                %                 app.EditField_out5.Value = 0;
                %                 app.EditField_out6.Value = 0;
                %                 app.EditField_out7.Value = 0;
                %                 app.EditField_out8.Value = 0;
                %                 app.EditField_out9.Value = 0;
                %                 app.EditField_out10.Value = 0;
                %                 app.EditField_out11.Value = 0;
                %                 app.EditField_out12.Value = 0;
                app.EditField_out1.Visible = 'off';
                app.EditField_out2.Visible = 'off';
                app.EditField_out3.Visible = 'off';
                app.EditField_out4.Visible = 'off';
                app.EditField_out5.Visible = 'off';
                app.EditField_out6.Visible = 'off';
                app.EditField_out7.Visible = 'off';
                app.EditField_out8.Visible = 'off';
                app.EditField_out9.Visible = 'off';
                app.EditField_out10.Visible = 'off';
                app.EditField_out11.Visible = 'off';
                app.EditField_out12.Visible = 'off';
                %输出单位
                app.dwBQLabel_out1.Text = "rad";
                app.dwBQLabel_out2.Text = "rad/s";
                app.dwBQLabel_out3.Text = "rad/s²";
                app.dwBQLabel_out4.Text = "rad";
                app.dwBQLabel_out5.Text = "rad/s";
                app.dwBQLabel_out6.Text = "rad/s²";
                %app.dwBQLabel_out7.Text = "XXXXX";
                app.dwBQLabel_out8.Text = "mm/s";
                app.dwBQLabel_out9.Text = "mm/s²";
                %app.dwBQLabel_out10.Text = "XXXXX";
                app.dwBQLabel_out11.Text = "mm/s";
                app.dwBQLabel_out12.Text = "mm/s²";
                app.dwBQLabel_out1.Visible = 'off';
                app.dwBQLabel_out2.Visible = 'off';
                app.dwBQLabel_out3.Visible = 'off';
                app.dwBQLabel_out4.Visible = 'off';
                app.dwBQLabel_out5.Visible = 'off';
                app.dwBQLabel_out6.Visible = 'off';
                app.dwBQLabel_out7.Visible = 'off';
                app.dwBQLabel_out8.Visible = 'off';
                app.dwBQLabel_out9.Visible = 'off';
                app.dwBQLabel_out10.Visible = 'off';
                app.dwBQLabel_out11.Visible = 'off';
                app.dwBQLabel_out12.Visible = 'off';
                
%                 app.DialogApp = Machine_Dialog(app);
                    
            elseif strcmpi(name, '                  齿轮机构')
                beep;
                uialert(app.UIFigure,'对不起，齿轮机构暂不开放','无效操作');
                app.TipsLabel.Text = "【Tips】：当前齿轮机构分析板块不开放，请重新选择";
%                 %已知参数
%                 % 四杆机构标签名
%                 app.BQLabel_in1.Text = "曲柄AB长度";
%                 app.BQLabel_in1.Visible = 'on';
%                 app.BQLabel_in2.Text = "连杆BC长度";
%                 app.BQLabel_in2.Visible = 'on';
%                 app.BQLabel_in3.Text = "连架杆CD长度";
%                 app.BQLabel_in3.Visible = 'on';
%                 app.BQLabel_in4.Text = "机架AD长度";
%                 app.BQLabel_in4.Visible = 'on';
%                 app.BQLabel_in5.Text = "曲柄转速ω1";
%                 app.BQLabel_in5.Visible = 'on';
%                 app.BQLabel_in6.Text = "曲柄转动加速度a";
%                 app.BQLabel_in6.Visible = 'on';
%                 app.BQLabel_in7.Text = "曲柄起角Φ";
%                 app.BQLabel_in7.Visible = 'on';
%                 % 四杆机构初始值
%                 app.l1 = 101.6;
%                 app.l2 = 254;
%                 app.l3 = 177.8;
%                 app.l4 = 304.8;
%                 app.omega1 = 250;
%                 app.alpha1 = 0;
%                 app.fir_theta = 70;
%                 app.EditField_in1.Value = app.l1;
%                 app.EditField_in2.Value = app.l2;
%                 app.EditField_in3.Value = app.l3;
%                 app.EditField_in4.Value = app.l4;
%                 app.EditField_in5.Value = app.omega1;
%                 app.EditField_in6.Value = app.alpha1;
%                 app.EditField_in7.Value = app.fir_theta;
%                 % 四杆机构数值可见性
%                 app.EditField_in1.Visible = 'on';
%                 app.EditField_in2.Visible = 'on';
%                 app.EditField_in3.Visible = 'on';
%                 app.EditField_in4.Visible = 'on';
%                 app.EditField_in5.Visible = 'on';
%                 app.EditField_in6.Visible = 'on';
%                 app.EditField_in7.Visible = 'on';
%                 % 四杆机构单位
%                 app.dwBQLabel_in1.Text = "mm";
%                 app.dwBQLabel_in2.Text = "mm";
%                 app.dwBQLabel_in3.Text = "mm";
%                 app.dwBQLabel_in4.Text = "mm";
%                 app.dwBQLabel_in5.Text = "rad/s";
%                 app.dwBQLabel_in6.Text = "rad/s²";
%                 app.dwBQLabel_in7.Text = "°";
%                 % 四杆机构单位可见性
%                 app.dwBQLabel_in1.Visible = 'on';
%                 app.dwBQLabel_in2.Visible = 'on';
%                 app.dwBQLabel_in3.Visible = 'on';
%                 app.dwBQLabel_in4.Visible = 'on';
%                 app.dwBQLabel_in5.Visible = 'on';
%                 app.dwBQLabel_in6.Visible = 'on';
%                 app.dwBQLabel_in7.Visible = 'on';
%                 
%                 %坐标区结构模型
%                 % 四杆机构结构模型
%                 cla(app.UIAxes_1);%清除坐标系
%                 theta2 = zeros(1,200);
%                 theta3 = zeros(1,200);
%                 omega2 = zeros(1,200);
%                 omega3 = zeros(1,200);
%                 alpha2 = zeros(1,200);
%                 alpha3 = zeros(1,200);
%                 
%                 %调用子函数crank_rocker计算铰链四杆机构位移，角速度，角加速度
%                 for n1 = 1:361
%                     theta1 = (n1 - 1) * app.hd;
%                     [theta,omega,alpha] = crank_rocker(app, theta1, app.omega1, app.alpha1,app.l1,app.l2,app.l3,app.l4);
%                     theta2(n1) = theta(1);
%                     theta3(n1) = theta(2);
%                     omega2(n1) = omega(1);
%                     omega3(n1) = omega(2);
%                     alpha2(n1) = alpha(1);
%                     alpha3(n1) = alpha(2);
%                 end
%                 x(1) = 0;
%                 y(1) = 0;
%                 x(2) = app.l1*cos(app.fir_theta * app.hd);
%                 y(2) = app.l1*sin(app.fir_theta * app.hd);
%                 x(3) = app.l4+app.l3*cos(theta3(app.fir_theta+1));
%                 y(3) = app.l3*sin(theta3(app.fir_theta+1));
%                 x(4) = app.l4;
%                 y(4) = 0;
%                 x(5) = 0;
%                 y(5) = 0;
%                 plot(app.UIAxes_1, x, y);
%                 grid(app.UIAxes_1, "on");
%                 hold(app.UIAxes_1, "on");
%                 plot(app.UIAxes_1, [0 app.l4], [0 0], "LineWidth", 2, "Color", [0.6350 0.0780 0.1840]);
%                 plot(app.UIAxes_1, x(1), y(1), 'o');
%                 plot(app.UIAxes_1, x(2), y(2), 'o');
%                 plot(app.UIAxes_1, x(3), y(3), 'o');
%                 plot(app.UIAxes_1, x(4), y(4), 'o');
%                 title(app.UIAxes_1, '四杆机构');
%                 xlabel(app.UIAxes_1, 'mm');
%                 ylabel(app.UIAxes_1, 'mm');
%                 axis(app.UIAxes_1, [-50 350 -50 203]);
%                 
%                 % 输出参数
%                 % 输出参数标签
%                 app.BQLabel_out1.Text = "连杆角位移";
%                 app.BQLabel_out2.Text = "连杆角速度";
%                 app.BQLabel_out3.Text = "连杆角加速度";
%                 app.BQLabel_out4.Text = "连架杆角位移";
%                 app.BQLabel_out5.Text = "连架杆角速度";
%                 app.BQLabel_out6.Text = "连架杆角加速度";
%                 app.BQLabel_out7.Text = "B点：";
%                 app.BQLabel_out8.Text = "B点速度";
%                 app.BQLabel_out9.Text = "B点加速度";
%                 app.BQLabel_out10.Text = "C点：";
%                 app.BQLabel_out11.Text = "C点速度";
%                 app.BQLabel_out12.Text = "C点加速度";
%                 app.BQLabel_out1.Visible = 'on';
%                 app.BQLabel_out2.Visible = 'on';
%                 app.BQLabel_out3.Visible = 'on';
%                 app.BQLabel_out4.Visible = 'on';
%                 app.BQLabel_out5.Visible = 'on';
%                 app.BQLabel_out6.Visible = 'on';
%                 app.BQLabel_out7.Visible = 'on';
%                 app.BQLabel_out8.Visible = 'on';
%                 app.BQLabel_out9.Visible = 'on';
%                 app.BQLabel_out10.Visible = 'on';
%                 app.BQLabel_out11.Visible = 'on';
%                 app.BQLabel_out12.Visible = 'on';
%                 %输出框
%                 %                 app.EditField_out1.Value = 0;
%                 %                 app.EditField_out2.Value = 0;
%                 %                 app.EditField_out3.Value = 0;
%                 %                 app.EditField_out4.Value = 0;
%                 %                 app.EditField_out5.Value = 0;
%                 %                 app.EditField_out6.Value = 0;
%                 %                 app.EditField_out7.Value = 0;
%                 %                 app.EditField_out8.Value = 0;
%                 %                 app.EditField_out9.Value = 0;
%                 %                 app.EditField_out10.Value = 0;
%                 %                 app.EditField_out11.Value = 0;
%                 %                 app.EditField_out12.Value = 0;
%                 app.EditField_out1.Visible = 'on';
%                 app.EditField_out2.Visible = 'on';
%                 app.EditField_out3.Visible = 'on';
%                 app.EditField_out4.Visible = 'on';
%                 app.EditField_out5.Visible = 'on';
%                 app.EditField_out6.Visible = 'on';
%                 app.EditField_out7.Visible = 'off';
%                 app.EditField_out8.Visible = 'on';
%                 app.EditField_out9.Visible = 'on';
%                 app.EditField_out10.Visible = 'off';
%                 app.EditField_out11.Visible = 'on';
%                 app.EditField_out12.Visible = 'on';
%                 %输出单位
%                 app.dwBQLabel_out1.Text = "rad";
%                 app.dwBQLabel_out2.Text = "rad/s";
%                 app.dwBQLabel_out3.Text = "rad/s²";
%                 app.dwBQLabel_out4.Text = "rad";
%                 app.dwBQLabel_out5.Text = "rad/s";
%                 app.dwBQLabel_out6.Text = "rad/s²";
%                 %app.dwBQLabel_out7.Text = "XXXXX";
%                 app.dwBQLabel_out8.Text = "mm/s";
%                 app.dwBQLabel_out9.Text = "mm/s²";
%                 %app.dwBQLabel_out10.Text = "XXXXX";
%                 app.dwBQLabel_out11.Text = "mm/s";
%                 app.dwBQLabel_out12.Text = "mm/s²";
%                 app.dwBQLabel_out1.Visible = 'on';
%                 app.dwBQLabel_out2.Visible = 'on';
%                 app.dwBQLabel_out3.Visible = 'on';
%                 app.dwBQLabel_out4.Visible = 'on';
%                 app.dwBQLabel_out5.Visible = 'on';
%                 app.dwBQLabel_out6.Visible = 'on';
%                 app.dwBQLabel_out7.Visible = 'off';
%                 app.dwBQLabel_out8.Visible = 'on';
%                 app.dwBQLabel_out9.Visible = 'on';
%                 app.dwBQLabel_out10.Visible = 'off';
%                 app.dwBQLabel_out11.Visible = 'on';
%                 app.dwBQLabel_out12.Visible = 'on';
%                 
%                 
%                 
            end
            
        end
        
        function [theta2, s3, omega2, v3, alpha2, a3] = slider_crank(~, theta1, omega1, alpha1, l1, l2, e)
            %计算连杆2的角位移和滑块3的线位移
            theta2 = asin((e - l1*sin(theta1))/l2);
            s3 = l1*cos(theta1) + l2*cos(theta2);
            
            %计算连杆2的角速度和滑块3的线速度
            A = [l2*sin(theta2), 1; -l2*cos(theta2), 0];
            B = [-l1*sin(theta1); l1*cos(theta1)];
            omega = A \ (omega1*B);
            omega2 = omega(1);
            v3 = omega(2);
            
            %计算连杆2的角加速度和滑块3的线加速度
            At = [omega2*l2*cos(theta2), 0; omega2*l2*sin(theta2),0];
            Bt = [-omega1*l1*cos(theta1); -omega1*l1*sin(theta1)];
            alpha = A \ (-At*omega + alpha1*B + omega1*Bt);
            alpha2 = alpha(1);
            a3 = alpha(2);
        end
        
        function caml(app)
             %凸轮曲线设计
            s = zeros(1,360);
            xx = zeros(1,360);
            yy = zeros(1,360);
            dx = zeros(1,360);
            dy = zeros(1,360);
            xp = zeros(1,360);
            yp = zeros(1,360);
            ss = zeros(1,360);
            delta = zeros(1,360);
            
            se = sqrt(app.r0^2 - app.e^2);
            nn1 = app.delta01 + app.delta02;
            nn3 = app.delta01 + app.delta02 + app.delta03;
            
            n = 360;
            ds = zeros(1,361);
            for i = 1:n
                %计算推杆运动规律
                if i <= app.delta01/2
                    s(i) = 2*app.h*i^2/app.delta01^2;
                    ds(i) = 4*app.h*i*app.hd/(app.delta01*app.hd)^2;
                    ds = ds(i);
                elseif i > app.delta01/2 && i <= app.delta01
                    s(i) = app.h - 2*app.h*(app.delta01 - i)^2/app.delta01^2;
                    ds(i) = 4*app.h*(app.delta01 - i)*app.hd/(app.delta01*app.hd)^2;
                    ds = ds(i);
                elseif i > app.delta01 && i <= nn1
                    s(i) = app.h;
                    ds = 0;
                elseif i > nn1 && i <= nn3
                    k = i - nn1;
                    s(i) = 0.5*app.h*(1 + cos(pi*k/app.delta03));
                    ds(i) = -0.5*pi*app.h*sin(pi*k/app.delta03)/(app.delta03*app.hd)^2;
                    ds = ds(i);
                elseif i > nn3 && i <= n
                    s(i) = 0;
                    ds = 0;
                end
                
                %计算凸轮轨迹曲线
                xx(i) = (se + s(i))*sin(i*app.hd) + app.e*cos(i*app.hd);
                yy(i) = (se + s(i))*cos(i*app.hd) - app.e*sin(i*app.hd);
                dx(i) = (ds - app.e)*sin(i*app.hd) + (se + s(i))*cos(i*app.hd);
                dy(i) = (ds - app.e)*cos(i*app.hd) - (se + s(i))*sin(i*app.hd);
                xp(i) = xx(i) + app.rr*dy(i)/sqrt(dx(i)^2 + dy(i)^2);
                yp(i) = yy(i) - app.rr*dx(i)/sqrt(dx(i)^2 + dy(i)^2);
            end
            
            %输出凸轮轮廓曲线
            %figure(1);
            hold(app.UIAxes_1, "on");
            grid(app.UIAxes_1, "on");
            axis(app.UIAxes_1, "equal");
            axis(app.UIAxes_1, [-(app.r0 + app.h - 30) (app.r0 + app.h + 10) -(app.r0 + app.h + 10) (app.r0 + app.rr + 10)]);
            text(app.UIAxes_1, app.r0 + app.h + 3, 4, 'X');
            text(app.UIAxes_1, 3, app.r0 + app.rr + 3, 'Y');
            text(app.UIAxes_1, -6, 4, 'O');
            title(app.UIAxes_1, '偏置直动滚子推杆盘形凸轮设计');
            xlabel(app.UIAxes_1, 'x/mm');
            ylabel(app.UIAxes_1, 'y/mm');
            plot(app.UIAxes_1, [-(app.r0 + app.h - 40) (app.r0 + app.h)], [0, 0], 'k');
            plot(app.UIAxes_1, [0,0], [-(app.r0 + app.h) (app.r0 + app.rr)], 'k');
            plot(app.UIAxes_1, xx, yy, 'r-');
            ct = linspace(0, 2*pi);
            plot(app.UIAxes_1, app.r0 * cos(ct), app.r0*sin(ct), 'g');
            plot(app.UIAxes_1, app.e*cos(ct), app.e*sin(ct), 'c-');
            plot(app.UIAxes_1, app.e + app.rr*cos(ct), se + app.rr*sin(ct),'k');
            plot(app.UIAxes_1, app.e, se, 'o');
            plot(app.UIAxes_1, [app.e app.e], [se se + 30], 'k');
            plot(app.UIAxes_1, xp, yp, 'b');
            
            %凸轮机构运动仿真
            %计算滚子转角
            xp0 = (app.r0 - app.rr)/app.r0*app.e;
            yp0 = (app.r0 - app.rr)/app.r0*se;
            dss = sqrt(diff(xp).^2 + diff(yp).^2);
            ss(1) = sqrt((xp(1) - xp0)^2 + (xp(1) - yp0)^2);
            for i = 1:359
                ss(i + 1) = ss(i) + dss(i);
            end
            phi = ss/app.rr;
            %仿真
            %figure(2);
            %m = moviein(20);
            %j = 0;
            for n = 1:1000
                if app.stop_sign
                    app.stop_sign = false;
                    break;
                end
                for i = 1:360
                    if app.stop_sign
                        %app.stop_sign = false;
                        break;
                    end
                    %j = j + 1;
                    delta(i) = i*app.hd;
                    xy = [xp', yp'];
                    Al = [cos(delta(i)), sin(delta(i)); -sin(delta(i)), cos(delta(i))];
                    xy = xy*Al;
                    %clf;
                    cla(app.UIAxes_2);
                    %绘凸?
                    plot(app.UIAxes_2, xy(:, 1), xy(:, 2));
                    hold(app.UIAxes_2, "on");
                    axis(app.UIAxes_2, "equal");
                    axis(app.UIAxes_2, [-(120) (470) -(100) (140)]);
                    plot(app.UIAxes_2, [-(app.r0 + app.h - 40) (app.r0 + app.h)], [0 0], 'k');
                    plot(app.UIAxes_2, [0 0], [-(app.r0 + app.h) (app.r0 + app.rr)], 'k');
                    plot(app.UIAxes_2, app.r0*cos(ct), app.r0*sin(ct), 'g');
                    plot(app.UIAxes_2, app.e*cos(ct), app.e*sin(ct), 'c-');
                    plot(app.UIAxes_2, app.e + app.rr*cos(ct), se + s(i) + app.rr*sin(ct), 'k');
                    plot(app.UIAxes_2, [app.e app.e + app.rr*cos(-phi(i))], [se + s(i) se + s(i) + app.rr*sin(-phi(i))], 'k');
                    %绘制滚子圆标?
                    plot(app.UIAxes_2, [app.e app.e], [se + s(i) se + s(i) + 40], 'k');
                    %绘推杆曲?
                    plot(app.UIAxes_2, [1:360] + app.r0 + app.h, s + se);
                    plot(app.UIAxes_2, [(app.r0 + app.h) (app.r0 + app.h + 360)], [se, se], 'k');
                    plot(app.UIAxes_2, [(app.r0 + app.h) (app.r0 + app.h)], [se, se + app.h], 'k');
                    plot(app.UIAxes_2, i + app.r0 + app.h, s(i) + se, '*');
                    title(app.UIAxes_2, "偏置直动滚子推杆盘形凸轮设计");
                    xlabel(app.UIAxes_2, 'x/mm');
                    ylabel(app.UIAxes_2, 'y/mm');
                    drawnow;
                    
                end
            end
            

        end
        
        function crank_rocker_force(app)
            % 输入已知参数
            %clear;
            l1 = 0.40;
            l2 = 1;
            l3 = 0.70;
            l4 = 1.200;
            las1 = 0.2;
            lbs2 = 0.5;
            lds3 = 0.35;
            omega1 = 10;
            hd = pi/180;
            du = 180/pi;
            
            %1 = 67.2;
            m1 = 1.2;
            m2 = 3;
            m3 = 2.2;
            g = 10;
            Js1 = 0.016;
            Js2 = 0.25;
            Js3 = 0.09;
            Mr = 100;
            
            % 铰链四杆机构运动分析
            for n1 = 1:360
                L = sqrt(l4 * l4 + l1 * l1 - 2 * l1 * l4 * cos(n1 * hd));
                phi(n1) = asin((l1/L) * sin(n1 * hd));
                beta(n1) = acos((-l2 * l2 + l3 * l3 + L * L)/(2 * l3 * L));
                if beta(n1) < 0
                    beta(n1) = beta(n1) + pi;
                end

                
                theta3(n1) = pi - phi(n1) - beta(n1); %theta3 表示杆3转过角度
                theta2(n1) = asin((l3 * sin(theta3(n1)) - l1 * sin(n1 * hd))/l2); %theta2 表示杆2转过角度
                omega3(n1) = omega1 * (l1 * sin((n1 * hd - theta2(n1))))/(l3 * sin((theta3(n1) - theta2(n1)))); %omega3 表示杆3角速度
                omega2(n1) = -omega1 * (l1 * sin((n1 * hd - theta3(n1))))/(l2 * sin((theta2(n1) - theta3(n1)))); %omega2 表示杆2角速度
                alpha3(n1) = (omega1^2 * l1 * cos((n1 * hd - theta2(n1))) + omega2(n1)^2 * l2 - omega3(n1)^2 * l3 * cos((theta3(n1) - theta2(n1))))/(l3 * sin((theta3(n1) - theta2(n1))));
                alpha2(n1) = (-omega1^2 * l1 * cos((n1 * hd - theta3(n1))) + omega3(n1)^2 * l3 - omega2(n1)^2 * l2 * cos((theta2(n1) - theta3(n1))))/(l2 * sin((theta2(n1) - theta3(n1))));
                
                %铰链四杆机构力平衡计算
                % 计算质心速度
                as1x(n1) = -las1 * omega1^2 * cos(n1 * hd);  %质心s1在x轴的加速度
                as1y(n1) = -las1 * omega1^2 * sin(n1 * hd);  %质心s1在y轴的加速度
                as2x(n1) = -l1 * omega1^2 * cos(n1 * hd) - lbs2 * (alpha2(n1) * sin(theta2(n1)) + omega2(n1)^2 * cos(theta2(n1))); %质心s2在x轴的加速度
                as2y(n1) = -l1 * omega1^2 * sin(n1 * hd) + lbs2 * (alpha2(n1) * cos(theta2(n1)) - omega2(n1)^2 * sin(theta2(n1))); %质心s2在y轴的加速度
                as3x(n1) = -lds3 * (cos(theta3(n1)) * omega3(n1)^2 + sin(theta3(n1)) * alpha3(n1)); %质心s3在x轴的加速度
                as3y(n1) = -lds3 * (sin(theta3(n1)) * omega3(n1)^2 - cos(theta3(n1)) * alpha3(n1)); %质心s3在y轴的加速度
                
                % 计算构件惯性力和惯性力矩
                F1x(n1) = -as1x(n1) * m1;
                Fly(n1) = -as1y(n1) * m1;
                F2x(n1) = -as2x(n1) * m2;
                F2y(n1) = -as2y(n1) * m2;
                F3x(n1) = -as3x(n1) * m3;
                F3y(n1) = -as3y(n1) * m3;
                M2(n1) = -alpha2(n1) * Js2;
                M3(n1) = -alpha3(n1) * Js3 - Mr;
                
                % 计算各个铰链点坐标，计算各个质心点坐标
                xa = 0;
                ya = 0;
                xb(n1) = l1 * cos(n1 * hd);
                yb(n1) = l1 * sin(n1 * hd);
                xc(n1) = 14 + 13 * cos(theta3(n1));
                yc(n1) = l3 * sin(theta3(n1));
                xd = l4;
                yd = 0;
                xs1(n1) = (xb(n1) + xa)/2;
                ys1(n1) = (yb(n1) + ya)/2;
                xs2(n1) = (xb(n1) + xc(n1))/2;
                ys2(n1) = (yb(n1) + yc(n1))/2;
                xs3(n1) = (xc(n1) + xd)/2;
                ys3(n1) = (yc(n1) + yd)/2;
                
                %未知力系数矩阵
                A = zeros(9);
                A(1,1) = 1;
                A(1,2) = -(ys1(n1) - ya);
                A(1,3) = -(xa - xs1(n1));
                A(1,4) = -(ys1(n1) - yb(n1));
                A(1,5) = -(xb(n1) - xs1(n1));
                A(2,2) = -1;
                A(2,4) = -1;
                A(3,3) = -1;
                A(3,5) = -1;
                A(4,4)=(ys2(n1) - yb(n1));
                A(4,5) = (xb(n1) - xs2(n1));
                A(4,6) = -(ys2(n1) - yc(n1));
                A(4,7) = -(xc(n1) - xs2(n1));
                A(5,4) = 1;
                A(5,6) = -1;
                A(6,5) = 1;
                A(6,7) = -1;
                A(7,6) = (ys3(n1) - yc(n1));
                A(7,7) = (xc(n1) - xs3(n1));
                A(7,8) = -(ys3(n1) - yd);
                A(7,9) = -(xd - xs3(n1));
                A(8,6) = 1;
                A(8,8) = -1;
                A(9,7) = 1;
                A(9,9) = -1;
                
                % 已知力列
                B = zeros(9,1);
                B(1) = 0;
                B(2) = -F1x(n1);
                B(3) = -Fly(n1) + m1 * g; %B(3) --Fly(nl) ；
                B(4) = -M2(n1);
                B(5) = -F2x(n1);
                B(6) = -F2y(n1) + m2 * g; %B(6)=-F2y(nl)；
                B(7) = -M3(n1);
                B(8) = -F3x(n1);
                B(9) = -F3y(n1) + m3 * g; %B(9)F3y(nl)；
                C = A \ B;
                Mb(n1) = C(1);
                Fr14x(n1) = C(2);
                Fr14y(n1) = C(3);
                Fr12x(n1) = C(4);
                Fr12y(n1) = C(5);
                Fr23x(n1) = C(6);
                Fr23y(n1) = C(7);
                Fr34x(n1) = C(8);
                Fr34y(n1) = C(9);
            end


            %铰链四杆机构分析图形输出
            %figure(4);
            n1 = 1:360;
            %subplot(app.UIAxes_41, 2, 2, 1);
%             plot(app.UIAxes_41, n1, Fr14x, 'b');
%             hold(app.UIAxes_41, "on");
%             plot(app.UIAxes_41, n1, Fr14y, 'k--');
%             legend(app.UIAxes_41, 'F_R_1_4_x', 'F_R_1_4_y');
%             title(app.UIAxes_41, '运动副反力F_R_1_4曲线图');
%             xlabel(app.UIAxes_41, '曲柄转角 \theta_1/ \circ');
%             ylabel(app.UIAxes_41, 'F/N');
%             grid(app.UIAxes_41, "on");
%             subplot(app.UIAxes_41, 2, 2, 2);
%             plot(app.UIAxes_41, n1, Fr23x, 'b');
%             hold(app.UIAxes_41, "on");
%             plot(app.UIAxes_41, n1, Fr23y, 'k--');
%             hold(app.UIAxes_41, "on");
%             legend(app.UIAxes_41, 'F_R_2_3_x', 'F_R_2_3_y');
%             title(app.UIAxes_41, '运动副反力F_R_2_3曲线图');
%             xlabel(app.UIAxes_41, '曲柄转角 \theta_1/ \circ');
%             ylabel(app.UIAxes_41, 'F/N');
%             grid(app.UIAxes_41, "on");
%             subplot(app.UIAxes_41, 2, 2, 3);
%             plot(app.UIAxes_41, n1, Fr34x, 'b');
%             hold(app.UIAxes_41, "on");
%             plot(app.UIAxes_41, n1, Fr34y, 'k--');
%             hold(app.UIAxes_41, "on");
%             legend(app.UIAxes_41, 'F_R_3_4_x', 'F_R_3_4_y');
%             title(app.UIAxes_41, '运动副反力F_R_3_4_x曲线图');
%             xlabel(app.UIAxes_41, '曲柄转角 \theta_1/ \circ');
%             ylabel(app.UIAxes_41, 'F/N');
%             subplot(app.UIAxes_41, 2, 2, 4);
            plot(app.UIAxes_42, n1, Mb);
            title(app.UIAxes_42, '力矩Mb图');
            xlabel(app.UIAxes_42, '曲柄转角 \theta_1/ \circ');
            ylabel(app.UIAxes_42, 'M/N*m');
            hold(app.UIAxes_42, "on");
            grid(app.UIAxes_42, "on");
            text(app.UIAxes_42, 100, 1.9 * 10^6, 'Mb');


        end
        
        function plotword(app)
            if (app.DropDown.Value == "                  四杆机构")
                % 四杆机构模型机构输出
                theta2 = zeros(1,200);
                theta3 = zeros(1,200);
                omega2 = zeros(1,200);
                omega3 = zeros(1,200);
                alpha2 = zeros(1,200);
                alpha3 = zeros(1,200);
                
                
                %调用子函数crank_rocker计算铰链四杆机构位移，角速度，角加速度
                for n1 = 1:361
                    theta1 = (n1 - 1) * app.hd;
                    [theta,omega,alpha] = crank_rocker(app, theta1, app.omega1, app.alpha1,app.l1, app.l2, app.l3, app.l4);
                    theta2(n1) = theta(1);
                    theta3(n1) = theta(2);
                    omega2(n1) = omega(1);
                    omega3(n1) = omega(2);
                    alpha2(n1) = alpha(1);
                    alpha3(n1) = alpha(2);
                end
                app.zft = figure('Units',"normalized",'Position', ...
                    [0.280469 0.553385 0.214453 0.251302], "Visible","off");
                n1 = 1:361;
                subplot(2, 2, 1);     %绘位移线图
                plot(n1, theta2 * app.du, n1, theta3 * app.du, 'k');
                title('角位移线图');
                xlabel('曲柄转角 \theta_1/ \circ');
                ylabel('角位移/ \circ');
                grid on;
                hold on;
                text(140, 170, '\theta_3');
                text(140, 30, '\theta_2');
                
                subplot(2,2,2);     %绘角速度图
                plot(n1, omega2, n1, omega3, 'k');
                title('角速度线图');
                xlabel('曲柄转角 \theta_1/ \circ');
                ylabel('角速度/rad \cdots^{-1}');
                grid on;
                hold on;
                text(250, 130, '\omega_2');
                text(130, 165, '\omega_3');
                
                subplot(2,2,3);     %绘角速度图
                plot(n1, alpha2, n1, alpha3, 'k');
                title('角加速度线图');
                xlabel('曲柄转角 \theta_1/ \circ');
                ylabel('角速度/rad \cdots^{-2}');
                grid on;
                hold on;
                text(230, 2e4, '\alpha_2');
                text(30, 7e4, '\alpha_3');
                
                subplot(2,2,4);     %绘模型图
                x(1) = 0;
                y(1) = 0;
                x(2) = app.l1*cos(70 * app.hd);
                y(2) = app.l1*sin(70 * app.hd);
                x(3) = app.l4+app.l3*cos(theta3(71));
                y(3) = app.l3*sin(theta3(71));
                x(4) = app.l4;
                y(4) = 0;
                x(5) = 0;
                y(5) = 0;
                plot(x,y);
                grid on;
                hold on;
                plot(x(1), y(1), 'o');
                plot(x(2), y(2), 'o');
                plot(x(3), y(3), 'o');
                plot(x(4), y(4), 'o');
                title('铰链四杆机构');
                xlabel('mm');
                ylabel('mm');
                axis([-50 350 -20 200]);
                
            elseif (app.DropDown.Value == "              曲柄滑块机构")
                %基本参数
             
                %调用子函数slider_crank计算曲柄滑块机构位移、速度、加速度
                theta1 = zeros(1,720);
                theta2 = zeros(1,720);
                s3 = zeros(1,720);
                omega2 = zeros(1,720);
                v3 = zeros(1,720);
                alpha2 = zeros(1,720);
                a3 = zeros(1,720);
                for n1 = 1:720
                    theta1(n1) = (n1 - 1) * app.hd;
                    [theta2(n1), s3(n1), omega2(n1), v3(n1), alpha2(n1), a3(n1)] = slider_crank(app, theta1(n1), app.omega1, app.alpha1, app.l1, app.l2, app.e);
                end
                
                %位移、速度、加速度和曲柄滑块机构图形输出
                app.zft = figure('Units',"normalized",'Position', ...
                    [0.280469 0.553385 0.214453 0.251302], "Visible","off");
                %n1 = 1:720;
                subplot(2,2,1);
                % [AX, ~, ~] = plotyy(theta1*du, theta2*du, theta1*du, s3);
                % set(get(AX(1),'ylabel'), 'String', '连杆角位移/ \circ');
                % set(get(AX(2),'ylabel'), 'String', '滑块位移/mm');
                x = theta1*app.du;
                y = theta2*app.du;
                yyaxis left;
                plot(x,y);
                ylabel('连杆角位移/ \circ');
                z = s3;
                yyaxis right;
                plot(x,z);
                title('位移线图');
                xlabel('曲柄转角 \theta_1/ \circ');
                ylabel('滑块位移/mm');
                grid on;
                
                subplot(2,2,2);
                % [AX, ~, ~] = plotyy(theta1*du, omega2, theta1*du, v3);
                x = theta1*app.du;
                y = omega2;
                yyaxis left;
                plot(x,y);
                ylabel('滑块速度/mm \cdots^{-1}');
                z = v3;
                yyaxis right;
                plot(x,z);
                title('速度线图');
                xlabel('曲柄转角 \theta_1/ \circ');
                ylabel('连杆角速度 /rad \cdots^{-1}');
                %set(get(AX(2),'ylabel'), 'String', '滑块速度/mm \cdots^{-1}');
                grid on;
                
                subplot(2,2,3);
                %[AX, H1, H2] = plotyy(theta1*du, alpha2, theta1*du, a3);
                x = theta1*app.du;
                y = alpha2;
                yyaxis left;
                plot(x,y);
                title('加速度线图');
                xlabel('曲柄转角 \theta_1/ \circ');
                ylabel('连杆角加速度 /rad \cdots^{-2}');
                z = a3;
                yyaxis right;
                plot(x,z);
                ylabel('滑块加速度/mm \cdots^{-2}');
                %set(get(AX(2), 'ylabel'), 'String', '滑块加速度/mm \cdots^{-2}');
                grid on;
                
                subplot(2,2,4);
                x(1) = 0;
                y(1) = 0;
                x(2) = app.l1*cos(70*app.hd);
                y(2) = app.l1*sin(70*app.hd);
                x(3) = s3(70);
                y(3) = app.e;
                x(4) = s3(70);
                y(4) = 0;
                x(5) = 0;
                y(5) = 0;
                x(6) = x(3) - 40;
                y(6) = y(3) + 10;
                x(7) = x(3) + 40;
                y(7) = y(3) + 10;
                x(8) = x(3) + 40;
                y(8) = y(3) - 10;
                x(9) = x(3) - 40;
                y(9) = y(3) - 10;
                x(10) = x(3) - 40;
                y(10) = y(3) + 10;
                
                i = 1:5;
                plot(x(i), y(i));
                grid on;
                hold on;
                i = 6: 10;
                plot(x(i), y(i));
                title('曲柄滑块机构');
                grid on;
                hold on;
                xlabel('mm');
                ylabel('mm');
                axis([-50 400 -20 130]);
                plot(x(1), y(1), 'o');
                plot(x(2), y(2), 'o');
                plot(x(3), y(3), 'o');
            elseif (app.DropDown.Value == "                  凸轮机构")
                %已知参数
                r0 = 40;    %基圆半径
                rr = 10;   %滚子半径
                h = 60;     %行程
                e = 15;     %偏距
                delta01 = 100;      %推程运动角????等加??等减?
                delta02 = 60;       %远休?
                delta03 = 90;       %回程运动角????余弦运?
                hd = pi/180;        %?
                du = 180/pi;
                se = sqrt(r0^2 - e^2);
                n1 = delta01 + delta02;
                n3 = delta01 + delta02 + delta03;
                
                s = zeros(1,360);
                xx = zeros(1,360);
                yy = zeros(1,360);
                dx = zeros(1,360);
                dy = zeros(1,360);
                xp = zeros(1,360);
                yp = zeros(1,360);
                ss = zeros(1,360);
                delta = zeros(1,360);
                %凸轮曲线设计
                n = 360;
                ds = zeros(1,361);
                for i = 1:n
                    %计算推杆运动规律
                    if i <= delta01/2
                        s(i) = 2*h*i^2/delta01^2;
                        ds(i) = 4*h*i*hd/(delta01*hd)^2;
                        ds = ds(i);
                    elseif i > delta01/2 && i <= delta01
                        s(i) = h - 2*h*(delta01 - i)^2/delta01^2;
                        ds(i) = 4*h*(delta01 - i)*hd/(delta01*hd)^2;
                        ds = ds(i);
                    elseif i > delta01 && i <= n1
                        s(i) = h;
                        ds = 0;
                    elseif i > n1 && i <= n3
                        k = i - n1;
                        s(i) = 0.5*h*(1 + cos(pi*k/delta03));
                        ds(i) = -0.5*pi*h*sin(pi*k/delta03)/(delta03*hd)^2;
                        ds = ds(i);
                    elseif i > n3 && i <= n
                        s(i) = 0;
                        ds = 0;
                    end
                    
                    %计算凸轮轨迹曲线
                    xx(i) = (se + s(i))*sin(i*hd) + e*cos(i*hd);
                    yy(i) = (se + s(i))*cos(i*hd) - e*sin(i*hd);
                    dx(i) = (ds - e)*sin(i*hd) + (se + s(i))*cos(i*hd);
                    dy(i) = (ds - e)*cos(i*hd) - (se + s(i))*sin(i*hd);
                    xp(i) = xx(i) + rr*dy(i)/sqrt(dx(i)^2 + dy(i)^2);
                    yp(i) = yy(i) - rr*dx(i)/sqrt(dx(i)^2 + dy(i)^2);
                end
                
                app.zft = figure('Units',"normalized",'Position', ...
                    [0.280469 0.553385 0.214453 0.251302], "Visible","off");
                
                %输出凸轮轮廓曲线
                subplot(1, 2, 1);
                %figure(1);
                hold on;
                grid on;
                axis equal;
                axis([-(r0 + h - 30) (r0 + h + 10) -(r0 + h + 10) (r0 + rr + 10)]);
                text(r0 + h + 3, 4, 'X');
                text(3, r0 + rr + 3, 'Y');
                text(-6, 4, 'O');
                title('偏置直动滚子推杆盘形凸轮设计');
                xlabel('x/mm');
                ylabel('y/mm');
                plot([-(r0 + h - 40) (r0 + h)], [0, 0], 'k');
                plot([0,0], [-(r0 + h) (r0 + rr)], 'k');
                plot(xx, yy, 'r-');
                ct = linspace(0, 2*pi);
                plot(r0 * cos(ct), r0*sin(ct), 'g');
                plot(e*cos(ct), e*sin(ct), 'c-');
                plot(e + rr*cos(ct), se + rr*sin(ct),'k');
                plot(e, se, 'o');
                plot([e e], [se se + 30], 'k');
                plot(xp, yp, 'b');
                
                %凸轮机构运动仿真
                %计算滚子转角
                xp0 = (r0 - rr)/r0*e;
                yp0 = (r0 - rr)/r0*se;
                dss = sqrt(diff(xp).^2 + diff(yp).^2);
                ss(1) = sqrt((xp(1) - xp0)^2 + (xp(1) - yp0)^2);
                for i = 1:359
                    ss(i + 1) = ss(i) + dss(i);
                end
                phi = ss/rr;
                %仿真
                % figure(2);
                subplot(1, 2, 2);
                delta(i) = i*hd;
                xy = [xp', yp'];
                Al = [cos(delta(i)), sin(delta(i)); -sin(delta(i)), cos(delta(i))];
                xy = xy*Al;
                clf;
                %绘凸?
                plot(xy(:, 1), xy(:, 2));
                hold on;
                axis equal;
                axis([-(120) (470) -(100) (140)]);
                plot([-(r0 + h - 40) (r0 + h)], [0 0], 'k');
                plot([0 0], [-(r0 + h) (r0 + rr)], 'k');
                plot(r0*cos(ct), r0*sin(ct), 'g');
                plot(e*cos(ct), e*sin(ct), 'c-');
                plot(e + rr*cos(ct), se + s(i) + rr*sin(ct), 'k');
                plot([e e + rr*cos(-phi(i))], [se + s(i) se + s(i) + rr*sin(-phi(i))], 'k');
                %绘制滚子圆标?
                plot([e e], [se + s(i) se + s(i) + 40], 'k');
                %绘推杆曲?
                plot([1:360] + r0 + h, s + se);
                plot([(r0 + h) (r0 + h + 360)], [se, se], 'k');
                plot([(r0 + h) (r0 + h)], [se, se + h], 'k');
                plot(i + r0 + h, s(i) + se, '*');
                title("偏置直动滚子推杆盘形凸轮设计");
                xlabel('x/mm');
                ylabel('y/mm');


            elseif (app.DropDown.Value == "                  齿轮机构")
                
            end


        end
    end
    
    methods (Access = public)
        
        function updateplot(app)
            % Store inputs as properties
%             app.CurrentSize = sz;
%             app.CurrentColormap = c;
            
            % Update plot 
%             Z = peaks(sz);
%             surf(app.UIAxes,Z);
%             colormap(app.UIAxes,c);
            
            % Re-enable the Plot Options button
            app.saveButton.Enable = 'on';
        end
        
    end
    
    methods (Access = private)
        
        function saveMat(app, x, y)
            f = app.UIFigure;
            d = uiprogressdlg(f,'Title','数据处理中，请稍后',...
                'Message','正在导出Mat文件');
            d.Value = 0.33;
            d.Message = '加载数据';
            save('Points.mat','x','y');
            d.Value = 1;
            d.Message = '导出完毕';
            close(d);
            uialert(app.UIFigure,'数据导出成功！','数据操作', "Icon", "success");
            app.TipsLabel.Text = "【Tips】：Mat数据保存成功！";
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            f = app.UIFigure;
            f.Name = "Mechanism_Analyzer V5.0";
            updateplot(app)
            
%             %换icon
%             handles.output = hObject;
%             I = imread('machine.png');
%             javaImage = im2java(I);%①
%             newIcon = javax.swing.ImageIcon(javaImage);
%             figFrame = get(handles.figure1,'JavaFrame'); %取得Figure的JavaFrame。
%             figFrame.setFigureIcon(newIcon); %修改图标
%             newIcon=javax.swing.ImageIcon('machine.png'); %logo.jpg是自己的图标
%             figFrame=get(GUI.open,'JavaFrame'); %GUI.open是想改变的GUI名称
%             figFrame.setFigureIcon(newIcon);
%                 Frame=get(GUI.open,'JavaFrame'); %GUI.open是想改变的GUI名称
%                 set(Frame,'FigureIcon',javax.swing.ImageIcon('logo.jpg')) %logo.jpg是自己的图标
%             iconfile = 'machine.png';
%             chgicon(app,iconfile);


            %坐标区结构模型
            % 四杆机构结构模型
            cla(app.UIAxes_1);%清除坐标系
            theta2 = zeros(1,200);
            theta3 = zeros(1,200);
            omega2 = zeros(1,200);
            omega3 = zeros(1,200);
            alpha2 = zeros(1,200);
            alpha3 = zeros(1,200);
            
            %调用子函数crank_rocker计算铰链四杆机构位移，角速度，角加速度
            for n1 = 1:361
                theta1 = (n1 - 1) * app.hd;
                [theta,omega,alpha] = crank_rocker(app, theta1, app.omega1, app.alpha1,app.l1,app.l2,app.l3,app.l4);
                theta2(n1) = theta(1);
                theta3(n1) = theta(2);
                omega2(n1) = omega(1);
                omega3(n1) = omega(2);
                alpha2(n1) = alpha(1);
                alpha3(n1) = alpha(2);
            end
            x(1) = 0;
            y(1) = 0;
            x(2) = 101.6*cos(70 * app.hd);
            y(2) = 101.6*sin(70 * app.hd);
            x(3) = 304.8+177.8*cos(theta3(71));
            y(3) = 177.8*sin(theta3(71));
            x(4) = 304.8;
            y(4) = 0;
            x(5) = 0;
            y(5) = 0;
            
            %输出mat
            app.currentx = x;
            app.currenty = y;
                
            plot(app.UIAxes_1, x, y);
            grid(app.UIAxes_1, "on");
            hold(app.UIAxes_1, "on");
            plot(app.UIAxes_1, [0 app.l4], [0 0], "LineWidth", 2, "Color", [0.6350 0.0780 0.1840]);
            plot(app.UIAxes_1, x(1), y(1), 'o');
            plot(app.UIAxes_1, x(2), y(2), 'o');
            plot(app.UIAxes_1, x(3), y(3), 'o');
            plot(app.UIAxes_1, x(4), y(4), 'o');
            title(app.UIAxes_1, '四杆机构');
            xlabel(app.UIAxes_1, 'mm');
            ylabel(app.UIAxes_1, 'mm');
            axis(app.UIAxes_1, [-50 350 -50 203]);
            
            app.TipsLabel.Text = "【Tips】:欢迎进入仿真系统，当前处于四杆机构板块";
            updateplot(app)
        end

        % Close request function: UIFigure
        function MainAppCloseRequest(app, event)
            f = app.UIFigure;
            selection = uiconfirm(f,'未保存下将会丢失仿真数据，是否确认退出本次程序?','确认窗口',...
                'Icon','warning');
            %disp(selection);
            switch selection
                case 'OK'
                    delete(app.drawpoint);
                    delete(app.zhengni);
                    delete(app);
                    
                   
                case 'Cancel'
                    return
            end
            
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            Setfirst(app,value);
            app.UIAxes_1.Title.String = string(value);
            app.UIAxes_2.Title.String = string(value);
            app.UIAxes_31.Title.String = string(value);
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            cla(app.UIAxes_1);%清除坐标系
            cla(app.UIAxes_2);
            cla(app.UIAxes_31);
            cla(app.UIAxes_32);
            cla(app.UIAxes_33);
            %app.stop_sign = true;
            %disp("开始前的");
            %disp(app.l1);
            if ismember(app.DropDown.Value, "                  四杆机构")
                result = compare(app, app.EditField_in1.Value, app.EditField_in2.Value, app.EditField_in3.Value, app.EditField_in4.Value);
                
                %disp(result);
                if result ~= 0
                    if app.EditField_in1.Value == result
                        %执行四杆机构计算程序
                        app.stop_sign = false;
                        app.TipsLabel.Text = "【Tips】：当前处于四杆机构分析板块，运动仿真已开启";
                        crank_rocker_main(app);
                        %crank_rocker_force(app);
                    else
                        beep;
                        uialert(app.UIFigure,'非曲柄摇杆机构，请重新输入！','错误操作');
                        app.TipsLabel.Text = "【Tips】：非曲柄摇杆机构，请重新输入！','错误操作";
                        app.stop_sign = true;
                    end
                else
                    beep;
                    uialert(app.UIFigure,'不满足杆长之和条件，请重新输入！','错误操作');
                    app.TipsLabel.Text = "【Tips】：不满足杆长之和条件，请重新输入！','错误操作";
                    app.stop_sign = true;
                end
                
%                 t1 = app.EditField_in1.Value + app.EditField_in2.Value < app.EditField_in3.Value + app.EditField_in4.Value;
%                 t2 = app.EditField_in1.Value + app.EditField_in3.Value < app.EditField_in2.Value + app.EditField_in4.Value;
%                 t3 = app.EditField_in1.Value + app.EditField_in4.Value < app.EditField_in2.Value + app.EditField_in3.Value;
%                 if t1 && t2 && t3
%                     h1 = app.EditField_in1.Value < app.EditField_in2.Value;
%                     h2 = app.EditField_in1.Value < app.EditField_in3.Value;
%                     h3 = app.EditField_in1.Value < app.EditField_in4.Value;
%                     if h1 && h2 && h3
%                         crank_rocker_main(app);
%                     else
%                         uialert(app.UIFigure,'非曲柄摇杆机构，请重新输入！','错误操作');
%                     end
%                     
%                 else
%                     uialert(app.UIFigure,'不满足杆长之和条件，请重新输入！','错误操作');
%                 end
                
            elseif ismember(app.DropDown.Value, '                曲柄滑块机构')
                %执行曲柄滑块机构计算程序
                if app.l1 > app.l2
                    app.EditField_in2.Value = app.l1;
                    app.EditField_in2.Value = app.l2;
                    beep;
                    uialert(app.UIFigure,'对不起，无法构成曲柄滑块机构，请重新输入','无效操作');
                    app.TipsLabel.Text = "【Tips】：对不起，无法构成曲柄滑块机构，请重新输入','无效操作";
                else
                    app.TipsLabel.Text = "【Tips】：当前处于曲柄摇杆机构分析板块，运动仿真已开启";
                    slider_crank_main(app);
                    
                end
                
            elseif ismember(app.DropDown.Value, '                  凸轮机构')
                %执行凸轮机构计算程序
                app.TipsLabel.Text = "【Tips】：当前处于凸轮机构分析板块，运动仿真已开启";
                caml(app);
                
            elseif ismember(app.DropDown.Value, '                  齿轮机构')
                %执行齿轮机构计算程序
            end

        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.stop_sign = true;
        end

        % Value changed function: EditField_in1
        function EditField_in1ValueChanged(app, event)
            value = app.EditField_in1.Value;                                 %**********************************************bug
            app.l1 = value;
            %             select = app.DropDown.Value;
            %             if strcmpi(select, "                  四杆机构")
            %                 app.l1 = value;
            %             elseif strcmpi(select, "                曲柄滑块机构")
            %                 app.l1 = value;
            %             elseif strcmpi(select, "                  凸轮机构")
            %                 app.l1 = 0;
            %             elseif strcmpi(select, "                  齿轮机构")
            %                 app.l1 = 0;
            %             end
            if strcmpi(app.BQLabel_in1.Text, "曲柄AB长度") %机构为四杆或曲柄滑块
                app.l1 = value;
            end
            %disp("输入后的");
            %disp(value);
            %disp(app.l1);
        end

        % Value changed function: EditField_in2
        function EditField_in2ValueChanged(app, event)
            value = app.EditField_in2.Value;
            if strcmpi(app.BQLabel_in2.Text, "连杆BC长度") %机构为四杆或曲柄滑块
%                 if strcmpi(app.DropDown.Value, "                曲柄滑块机构")
%                     if value < app.l1   
%                         app.EditField_in2.Value = app.l2;
%                         uialert(app.UIFigure,'对不起，无法构成曲柄滑块机构，请重新输入','无效操作');
%                         
%                     end
%                     app.l2 = value;
%                 end
                app.l2 = value;
            end
            
        end

        % Value changed function: EditField_in3
        function EditField_in3ValueChanged(app, event)
            value = app.EditField_in3.Value;
            if strcmpi(app.BQLabel_in3.Text, "连架杆CD长度") %机构为四杆
                app.l3 = value;
            elseif strcmpi(app.BQLabel_in3.Text, "偏距") %机构为曲柄滑块
                %app.e = value;
                app.EditField_in3.Value = 0;
                beep;
                uialert(app.UIFigure,'对不起，偏距暂不开放修改','无效操作');
                app.TipsLabel.Text = "【Tips】：对不起，偏距暂不开放修改','无效操作";
            end
            
        end

        % Value changed function: EditField_in4
        function EditField_in4ValueChanged(app, event)
            value = app.EditField_in4.Value;
            if strcmpi(app.BQLabel_in4.Text, "机架AD长度") %机构为四杆
                app.l4 = value;
            elseif strcmpi(app.BQLabel_in4.Text, "曲柄转速ω1") %机构为曲柄滑块
                app.omega1 = value;
                
            end
        end

        % Value changed function: EditField_in5
        function EditField_in5ValueChanged(app, event)
            value = app.EditField_in5.Value;
            if strcmpi(app.BQLabel_in5.Text, "曲柄转速ω1") %机构为四杆
                app.omega1 = value;
            elseif strcmpi(app.BQLabel_in5.Text, "曲柄转动加速度a") %机构为曲柄滑块
                %app.alpha1 = value;
                if value ~= 0
                    app.EditField_in5.Value = 0;
                    beep;
                    uialert(app.UIFigure,'对不起，变加速运动暂不开放','无效操作');
                    app.TipsLabel.Text = "【Tips】：对不起，变加速运动暂不开放','无效操作";
                end
            end
            
        end

        % Value changed function: EditField_in6
        function EditField_in6ValueChanged(app, event)
            value = app.EditField_in6.Value;
            if strcmpi(app.BQLabel_in6.Text, "曲柄转动加速度a") %机构为四杆
                %app.alpha1 = value;
                if value ~= 0
                    app.EditField_in6.Value = 0;
                    beep;
                    uialert(app.UIFigure,'对不起，变加速运动暂不开放','无效操作');
                    app.TipsLabel.Text = "【Tips】：对不起，变加速运动暂不开放','无效操作";
                end
            elseif strcmpi(app.BQLabel_in6.Text, "曲柄起角Φ") %机构为曲柄滑块
                app.fir_theta = value;
            end
%             if (value ~= 0) && strcmpi(app.DropDown.Value, "                  四杆机构")
%                 %f = uifigure;
%                 uialert(app.UIFigure,'对不起，变加速运动暂不开放','无效操作');
%             end
        end

        % Value changed function: EditField_in7
        function EditField_in7ValueChanged(app, event)
            value = app.EditField_in7.Value;
            if strcmpi(app.BQLabel_in7.Text, "曲柄起角Φ") %机构为四杆
                app.fir_theta = value;
            end
        end

        % Button pushed function: saveButton
        function saveButtonPushed(app, event)
%             app.saveButton.Enable = "off";
%             app.DialogApp = Machine_Dialog(app);
            if (app.DropDown.Value == "                  四杆机构")
                app.save_Signal = 1;
                uialert(app.UIFigure,'四杆机构数据保存成功！','数据操作', "Icon", "success");
                app.TipsLabel.Text = "【Tips】：四杆机构数据保存成功！";
            elseif (app.DropDown.Value == "              曲柄滑块机构")
                app.save_Signal = 1;
                uialert(app.UIFigure,'曲柄摇杆机构数据保存成功！','数据操作', "Icon", "success");
                app.TipsLabel.Text = "【Tips】：曲柄摇杆机构数据保存成功！";
            elseif (app.DropDown.Value == "                  凸轮机构")  
                app.save_Signal = 1;
                uialert(app.UIFigure,'凸轮机构数据保存成功！','数据操作', "Icon", "success");
                app.TipsLabel.Text = "【Tips】：凸轮机构数据保存成功！";
            elseif (app.DropDown.Value == "                  齿轮机构")
                app.save_Signal = 0;
                uialert(app.UIFigure,'齿轮机构数据保存失败！','数据操作', "Icon", "error");
                app.TipsLabel.Text = "【Tips】：齿轮机构数据保存失败！";
            end
        end

        % Menu selected function: Menu_11
        function Menu_11Selected(app, event)
            uigetfile();
        end

        % Menu selected function: Menu_132
        function Menu_132Selected(app, event)
            if(app.save_Signal == 1)
                
                %利用MATLAB生成Word文档
                %	原摘自xiezhh，根据论坛上的相关建议，做了稍微的改动和完善
                
                %创建进度框
                f = app.UIFigure;
                d = uiprogressdlg(f,'Title','数据处理中，请稍后',...
                    'Message','正在打开Microsoft Word');
                
                
                filespec_user = [pwd '\四杆机构运动学与动力学分析.doc'];
                
                %===启用word调用功能========================================================
                try
                    Word = actxGetRunningServer('Word.Application');
                catch
                    Word = actxserver('Word.Application');
                end
                Word.Visible = 1; % 使word为可见；或set(Word, 'Visible', 1);
                %===打开word文件，如果路径下没有则创建一个空白文档打开========================
                if exist(filespec_user,'file')
                    Document = Word.Documents.Open(filespec_user);
                else
                    Document = Word.Documents.Add;
                    Document.SaveAs2(filespec_user);
                end
                %===格式定义===============================================================
                Content = Document.Content;
                Selection = Word.Selection;
                Paragraphformat = Selection.ParagraphFormat;
                %===文档的页边距===========================================================
                Document.PageSetup.TopMargin    = 60;
                Document.PageSetup.BottomMargin = 45;
                Document.PageSetup.LeftMargin   = 45;
                Document.PageSetup.RightMargin  = 45;
                %==========================================================================
                
                %进度30%
                d.Value = 0.33;
                d.Message = '加载数据';
                
                
                %===文档组成部分============================================================
                % 文档的标题及格式
                headline            = '机构分析报告';
                Content.Start       = 0;    % 起始点为0，即表示每次写入覆盖之前资料
                Content.Text        = headline;
                Content.Font.Size   = 16;   % 字体大小
                Content.Font.Bold   = 1;    % 字体加粗
                Content.Paragraphs.Alignment = 'wdAlignParagraphCenter'; % 居中,wdAlignParagraphLeft/Center/Right
                
                % 文档的创建时间
                Selection.Start     = Content.end;  % 开始的地方在上一个的结尾
                Selection.TypeParagraph;            % 插入一个新的空段落
                % 插入时间
                currentdate         = datestr(now, 0);  % 获取当前时间
                Selection.Text      = currentdate;      % 当前时间作为输出
                Selection.Font.Size = 12;               % 字号
                Selection.Font.Bold = 0;                % 不加粗
                Selection.MoveDown;                     %将所选内容向下移动，并返回移动距离的单位数
                Paragraphformat.Alignment = 'wdAlignParagraphCenter'; % 居中
                
                % 插入回车
                Selection.TypeParagraph;% 插入一个新的空段落
                Selection.Font.Size = 10.5;% 新的空段落字号
                
                % 插入表格
                Selection.Start     = Content.end;
                Selection.TypeParagraph;
                Paragraphformat.Alignment = 'wdAlignParagraphLeft';
                Selection.MoveDown;
                
                Tables = Document.Tables.Add(Selection.Range,12,9);    % 插入一个12行9列的表格
                
                DTI = Document.Tables.Item(1); % 表格句柄
                
                DTI.Borders.OutsideLineStyle    = 'wdLineStyleSingle';  % 最外框，实线
                DTI.Borders.OutsideLineWidth    = 'wdLineWidth150pt';   % 线宽
                DTI.Borders.InsideLineStyle     = 'wdLineStyleSingle';  % 所有的内框线条
                DTI.Borders.InsideLineWidth     = 'wdLineWidth150pt';   % 线宽
                
                DTI.Rows.Alignment                          = 'wdAlignRowCenter'; %大表格居中
                DTI.Rows.Item(8).Borders.Item(1).LineStyle  = 'wdLineStyleNone'; % 第八行的上边线消失
                DTI.Rows.Item(8).Borders.Item(3).LineStyle  = 'wdLineStyleNone';% 第八行的下边线消失
                DTI.Rows.Item(11).Borders.Item(1).LineStyle = 'wdLineStyleNone';
                DTI.Rows.Item(11).Borders.Item(3).LineStyle = 'wdLineStyleNone';
                
                % 设置行高，列宽
                column_width = [53.7736,85.1434,53.7736,35.0094,...
                    35.0094,76.6981,55.1887,52.9245,54.9057];
                row_height = [28.5849,28.5849,28.5849,28.5849,25.4717,25.4717,...
                    32.8302,312.1698,17.8302,49.2453,14.1509,18.6792];
                
                for i = 1:9
                    DTI.Columns.Item(i).Width = column_width(i);
                end
                
                for i = 1:12
                    DTI.Rows.Item(i).Height = row_height(i);
                end
                
                % 设置垂直居中
                for i = 1:12        % 行
                    for j = 1:9     % 列
                        DTI.Cell(i,j).VerticalAlignment = 'wdCellAlignVerticalCenter';
                    end
                end
                
                % 合并单元格
                DTI.Cell(1, 4).Merge(DTI.Cell(1, 5)); % 第一行第四个到第五个合并
                DTI.Cell(2, 4).Merge(DTI.Cell(2, 5));
                DTI.Cell(3, 4).Merge(DTI.Cell(3, 5));
                DTI.Cell(4, 4).Merge(DTI.Cell(4, 5));
                DTI.Cell(5, 2).Merge(DTI.Cell(5, 5));
                DTI.Cell(5, 3).Merge(DTI.Cell(5, 6));
                DTI.Cell(6, 2).Merge(DTI.Cell(6, 5));
                DTI.Cell(6, 3).Merge(DTI.Cell(6, 6));
                DTI.Cell(5, 1).Merge(DTI.Cell(6, 1));
                DTI.Cell(7, 1).Merge(DTI.Cell(7, 9));
                DTI.Cell(8, 1).Merge(DTI.Cell(8, 9));
                DTI.Cell(9, 1).Merge(DTI.Cell(9, 3));
                DTI.Cell(9, 2).Merge(DTI.Cell(9, 3));
                DTI.Cell(9, 3).Merge(DTI.Cell(9, 4));
                DTI.Cell(9, 4).Merge(DTI.Cell(9, 5));
                DTI.Cell(10, 1).Merge(DTI.Cell(10, 9));% 第10行第1个到第9个合并
                DTI.Cell(11, 5).Merge(DTI.Cell(11, 9));
                DTI.Cell(12, 5).Merge(DTI.Cell(12, 9));
                DTI.Cell(11, 1).Merge(DTI.Cell(12, 4));
                
                %进度60%
                d.Value = 0.67;
                d.Message = '生成数据';
                
                
                % 表格之后的段落
                Selection.Start = Content.end;
                Selection.TypeParagraph;
                Selection.Text = '主管院长签字：            年    月    日';
                Paragraphformat.Alignment = 'wdAlignParagraphRight';
                Selection.MoveDown;
                
                % 定义表格中的内容
                DTI.Cell(1,1).Range.Text = '课程名称';
                %填
                DTI.Cell(1,2).Range.Text = '机械原理';
                DTI.Cell(1,3).Range.Text = '课程号';
                %填
                DTI.Cell(1,4).Range.Text = '03B20240';
                DTI.Cell(1,5).Range.Text = '任课教师学院';
                %填
                DTI.Cell(1,6).Range.Text = '机电工程学院';
                DTI.Cell(1,7).Range.Text = '任课教师';
                %填
                DTI.Cell(1,8).Range.Text = '何毅斌';
                DTI.Cell(2,1).Range.Text = '研究课题';
                %填
                DTI.Cell(2,2).Range.Text = '四足机器人';
                DTI.Cell(2,3).Range.Text = '考核日期';
                
                DTI.Cell(2,5).Range.Text = '机构选择';
                %填
                DTI.Cell(2,6).Range.Text = '曲柄滑块机构';
                DTI.Cell(2,7).Range.Text = '报告内容';
                %填
                DTI.Cell(2,8).Range.Text = '如下';
                DTI.Cell(3,1).Range.Text = '学生姓名';
                %填
                DTI.Cell(3,2).Range.Text = 'XXX';
                DTI.Cell(3,3).Range.Text = '学生学号';
                %填
                DTI.Cell(3,2).Range.Text = 'XXXXXXXXX';
                DTI.Cell(3,5).Range.Text = '学生班级';
                %填
                DTI.Cell(3,6).Range.Text = 'XXXXX';
                DTI.Cell(3,7).Range.Text = '所属学院';
                %填
                DTI.Cell(3,8).Range.Text = '机电工程学院';
                DTI.Cell(4,1).Range.Text = 'XXX';
                %填
                DTI.Cell(4,2).Range.Text = 'XXX';
                DTI.Cell(4,3).Range.Text = '学生学号';
                %填
                DTI.Cell(4,4).Range.Text = 'XXXXXXXX';
                DTI.Cell(4,5).Range.Text = '学生班级';
                %填
                DTI.Cell(4,6).Range.Text = 'XXXXX';
                DTI.Cell(4,7).Range.Text = '所属学院';
                %填
                DTI.Cell(4,8).Range.Text = '机电工程学院';
                DTI.Cell(5,1).Range.Text = '报告评分';
                DTI.Cell(5,2).Range.Text = '';%'90分以上      人占        %';
                DTI.Cell(5,3).Range.Text = '';%80---89分        人占        %';
                DTI.Cell(6,2).Range.Text = '';%'70--79分      人占        %';
                DTI.Cell(6,3).Range.Text = '';%'60---69分        人占        %';
                DTI.Cell(7,1).Range.Text = ['杆件分析（包含研究背景、研究目的、探究方案'...
                    '研究方法包括matlab联合动力学仿真、桌面应用程序开发、三维模型设计、三维及二维动画展示'...
                    '存在的问题及改进措施等内容）'];
                DTI.Cell(7,1).Range.ParagraphFormat.Alignment = 'wdAlignParagraphLeft';
                DTI.Cell(9,2).Range.Text = '签字 :';
                DTI.Cell(9,4).Range.Text = '年    月    日';
                DTI.Cell(10,1).Range.Text = '任课老师审阅意见：';
                DTI.Cell(10,1).Range.ParagraphFormat.Alignment = 'wdAlignParagraphLeft';
                DTI.Cell(10,1).VerticalAlignment = 'wdCellAlignVerticalTop';
                DTI.Cell(11,2).Range.Text = '任课老师（签字）:          年    月    日';
                DTI.Cell(11,2).Range.ParagraphFormat.Alignment = 'wdAlignParagraphLeft';
                DTI.Cell(8,1).Range.ParagraphFormat.Alignment = 'wdAlignParagraphLeft';
                DTI.Cell(8,1).VerticalAlignment = 'wdCellAlignVerticalTop';
                DTI.Cell(9,2).Borders.Item(2).LineStyle = 'wdLineStyleNone';
                DTI.Cell(9,2).Borders.Item(4).LineStyle = 'wdLineStyleNone';
                DTI.Cell(9,3).Borders.Item(4).LineStyle = 'wdLineStyleNone';
                DTI.Cell(11,1).Borders.Item(4).LineStyle = 'wdLineStyleNone';

                Shape = Document.Shapes;
                ShapeCount = Shape.Count;
                if ShapeCount ~= 0
                    for i = 1:ShapeCount
                        Shape.Item(1).Delete;
                    end
                end
                
                % 绘图并放置于表格单元(8,1)
                zft = figure('units','normalized','position',...
                    [0.280469 0.553385 0.428906 0.251302],'visible','off'); % 定义句柄
                % 绘图
                set(gca,'position',[0.1 0.2 0.85 0.75]);
                data = normrnd(0,1,1000,1);
                hist(data);
                grid on;
                xlabel('考试成绩');
                ylabel('人数');
                
                plotword(app);
                hgexport(app.zft, '-clipboard'); % 将图片复制到剪切板
                DTI.Cell(8,1).Range.Paragraphs.Item(1).Range.PasteSpecial; % 粘贴操作
                %Shape.Item(1).WrapFormat.Type = 3;
                %Shape.Item(1).ZOrder('msoBringInFrontOfText');
                delete(app.zft);    % 删除句柄
                
                Document.ActiveWindow.ActivePane.View.Type = 'wdPrintView';
                Document.Save;  % 保存文档
                
                %进度条到100%
                d.Value = 1;
                d.Message = '完成';
                close(d);
                %Word.Quit;      % 关闭文档
            else
                uialert(app.UIFigure,'数据导出失败，请重新操作！','请注意数据保存');
            end


        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            if strcmpi(value, 'on')
                app.Switch.FontColor = [0.6350 0.0780 0.1840];
                app.Switch.FontWeight = 'bold';
                uiwait(app.UIFigure);
            elseif strcmpi(value, 'off')
                app.Switch.FontColor = [193/255 193/255 193/255];
                app.Switch.FontWeight = 'normal';
                uiresume(gcbf);
            end
        end

        % Menu selected function: Menu_51
        function Menu_51Selected(app, event)
            %打开帮助文档
            winopen("User Documentation.pdf");
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.Button.Enable = "off";
            app.TipsLabel.Text = "【Tips】：正在调动正逆运动学窗口";
            app.zhengni = zhengni(app);
        end

        % Menu selected function: Menu_14
        function Menu_14Selected(app, event)
            app.save_Signal = 1;
        end

        % Menu selected function: Menu_21
        function Menu_21Selected(app, event)
            if (app.grid_Sign == 1)
                grid(app.UIAxes_1, "off");
                grid(app.UIAxes_2, "off");
                grid(app.UIAxes_31, "off");
                grid(app.UIAxes_32, "off");
                grid(app.UIAxes_33, "off");
                grid(app.UIAxes_41, "off");
                grid(app.UIAxes_42, "off");
                app.grid_Sign = 0;
            elseif (app.grid_Sign == 0)
                grid(app.UIAxes_1, "on");
                grid(app.UIAxes_2, "on");
                grid(app.UIAxes_31, "on");
                grid(app.UIAxes_32, "on");
                grid(app.UIAxes_33, "on");
                grid(app.UIAxes_41, "on");
                grid(app.UIAxes_42, "on");
            end
            
            
        end

        % Menu selected function: Menu_42
        function Menu_42Selected(app, event)
            app.TipsLabel.Text = "【Tips】：正在调动正逆运动学窗口";
            app.drawpoint = drawpoint(app);
        end

        % Menu selected function: matMenu
        function matMenuSelected(app, event)
            saveMat(app, app.currentx, app.currenty);
        end

        % Menu selected function: Menu_122
        function Menu_122Selected(app, event)
            [filename,pathname]=uigetfile('*.mat','打开数据');
            s=load(strcat([pathname filename])); %strcat是组成路径的，记不得是不是这么写了。
            app.l1 = sqrt((s.x(1) - s.x(2)) * (s.x(1) - s.x(2)) + (s.y(1) -s.y(2)) * (s.y(1) -s.y(2)));
            app.l2 = sqrt((s.x(2) - s.x(3)) * (s.x(2) - s.x(3)) + (s.y(2) -s.y(3)) * (s.y(2) -s.y(3)));
            app.l3 = sqrt((s.x(3) - s.x(4)) * (s.x(3) - s.x(4)) + (s.y(3) -s.y(4)) * (s.y(3) -s.y(4)));
            app.l4 = sqrt((s.x(4) - s.x(5)) * (s.x(4) - s.x(5)) + (s.y(4) -s.y(5)) * (s.y(4) -s.y(5)));
            uialert(app.UIFigure,'数据导入成功！','数据操作', "Icon", "success");
            app.TipsLabel.Text = "【Tips】：Mat数据导入成功！";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @MainAppCloseRequest, true);
            app.Menu_1 = uimenu(app.UIFigure);
            app.Menu_1.Text = '文件';
            app.Menu_11 = uimenu(app.Menu_1);
            app.Menu_11.MenuSelectedFcn = createCallbackFcn(app, @Menu_11Selected, true);
            app.Menu_11.Text = '打开';
            app.Menu_12 = uimenu(app.Menu_1);
            app.Menu_12.Text = '导入';
            app.Menu_121 = uimenu(app.Menu_12);
            app.Menu_121.Text = '图片';
            app.Menu_122 = uimenu(app.Menu_12);
            app.Menu_122.MenuSelectedFcn = createCallbackFcn(app, @Menu_122Selected, true);
            app.Menu_122.Text = 'mat文件';
            app.Menu_13 = uimenu(app.Menu_1);
            app.Menu_13.Text = '导出';
            app.Menu_131 = uimenu(app.Menu_13);
            app.Menu_131.Text = '导出成 PDF';
            app.Menu_132 = uimenu(app.Menu_13);
            app.Menu_132.MenuSelectedFcn = createCallbackFcn(app, @Menu_132Selected, true);
            app.Menu_132.Text = '导出成 Word';
            app.Menu_133 = uimenu(app.Menu_13);
            app.Menu_133.Text = '导出成 txt';
            app.matMenu = uimenu(app.Menu_13);
            app.matMenu.MenuSelectedFcn = createCallbackFcn(app, @matMenuSelected, true);
            app.matMenu.Text = '导出mat';
            app.Menu_14 = uimenu(app.Menu_1);
            app.Menu_14.MenuSelectedFcn = createCallbackFcn(app, @Menu_14Selected, true);
            app.Menu_14.Text = '保存';
            app.Menu_15 = uimenu(app.Menu_1);
            app.Menu_15.Text = '另存为';
            app.Menu_2 = uimenu(app.UIFigure);
            app.Menu_2.Text = '显示';
            app.Menu_21 = uimenu(app.Menu_2);
            app.Menu_21.MenuSelectedFcn = createCallbackFcn(app, @Menu_21Selected, true);
            app.Menu_21.Text = '网格显示';
            app.Menu_22 = uimenu(app.Menu_2);
            app.Menu_22.Text = '字体';
            app.Menu_23 = uimenu(app.Menu_2);
            app.Menu_23.Text = '界面风格';
            app.Menu_3 = uimenu(app.UIFigure);
            app.Menu_3.Text = '分析';
            app.Menu_31 = uimenu(app.Menu_3);
            app.Menu_31.Text = '运动学分析';
            app.Menu_32 = uimenu(app.Menu_3);
            app.Menu_32.Text = '动力学分析';
            app.Menu_4 = uimenu(app.UIFigure);
            app.Menu_4.Text = '工具';
            app.Menu_41 = uimenu(app.Menu_4);
            app.Menu_41.Text = '快捷键';
            app.Menu_42 = uimenu(app.Menu_4);
            app.Menu_42.MenuSelectedFcn = createCallbackFcn(app, @Menu_42Selected, true);
            app.Menu_42.Text = '图形绘制';
            app.Menu_5 = uimenu(app.UIFigure);
            app.Menu_5.Text = '帮助';
            app.Menu_51 = uimenu(app.Menu_5);
            app.Menu_51.MenuSelectedFcn = createCallbackFcn(app, @Menu_51Selected, true);
            app.Menu_51.Text = '帮助文档';
            app.Menu_52 = uimenu(app.Menu_5);
            app.Menu_52.Text = 'app开发';
            app.Menu_521 = uimenu(app.Menu_52);
            app.Menu_521.Text = '答谢开发者';
            app.Menu_5211 = uimenu(app.Menu_521);
            app.Menu_5211.Text = 'Demons';
            app.Menu_522 = uimenu(app.Menu_52);
            app.Menu_522.Text = '更新建议';
            app.Menu_5221 = uimenu(app.Menu_522);
            app.Menu_5221.Text = 'demeonssssssss@outlook.com';
            app.Menu_523 = uimenu(app.Menu_52);
            app.Menu_523.Text = '其他';
            app.Menu_5231 = uimenu(app.Menu_523);
            app.Menu_5231.Text = '公众号：凡躯智核';
            app.Menu_5232 = uimenu(app.Menu_523);
            app.Menu_5232.Text = 'CSDN：https://blog.csdn.net/qq_43756951';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.Tab_1 = uitab(app.TabGroup);
            app.Tab_1.Title = '结构模型';
            app.UIAxes_1 = uiaxes(app.Tab_1);
            title(app.UIAxes_1, '四杆机构')
            xlabel(app.UIAxes_1, '')
            ylabel(app.UIAxes_1, '     ')
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '动态仿真';
            app.UIAxes_2 = uiaxes(app.Tab_2);
            title(app.UIAxes_2, '四杆机构')
            xlabel(app.UIAxes_2, '')
            ylabel(app.UIAxes_2, '     ')
            app.Tab_3 = uitab(app.TabGroup);
            app.Tab_3.Title = '运动学分析';
            app.TabGroup2_1 = uitabgroup(app.Tab_3);
            app.Tab_31 = uitab(app.TabGroup2_1);
            app.Tab_31.Title = '角位移线图';
            app.UIAxes_31 = uiaxes(app.Tab_31);
            title(app.UIAxes_31, 'Title')
            xlabel(app.UIAxes_31, 'X')
            ylabel(app.UIAxes_31, 'Y')
            app.Tab_32 = uitab(app.TabGroup2_1);
            app.Tab_32.Title = '角速度线图';
            app.UIAxes_32 = uiaxes(app.Tab_32);
            title(app.UIAxes_32, 'Title')
            xlabel(app.UIAxes_32, 'X')
            ylabel(app.UIAxes_32, 'Y')
            app.Tab_33 = uitab(app.TabGroup2_1);
            app.Tab_33.Title = '角加速度线图';
            app.UIAxes_33 = uiaxes(app.Tab_33);
            title(app.UIAxes_33, 'Title')
            xlabel(app.UIAxes_33, 'X')
            ylabel(app.UIAxes_33, 'Y')
            app.Tab_4 = uitab(app.TabGroup);
            app.Tab_4.Title = '动态静力分析';
            app.TabGroup2_2 = uitabgroup(app.Tab_4);
            app.Tab_41 = uitab(app.TabGroup2_2);
            app.Tab_41.Title = '运动副反力图';
            app.UIAxes_41 = uiaxes(app.Tab_41);
            title(app.UIAxes_41, 'Title')
            xlabel(app.UIAxes_41, 'X')
            ylabel(app.UIAxes_41, 'Y')
            app.Tab_42 = uitab(app.TabGroup2_2);
            app.Tab_42.Title = '力矩曲线图';
            app.UIAxes_42 = uiaxes(app.Tab_42);
            title(app.UIAxes_42, 'Title')
            xlabel(app.UIAxes_42, 'X')
            ylabel(app.UIAxes_42, 'Y')
            app.Panel = uipanel(app.GridLayout);
            app.Panel.Title = '已知参数';
            app.GridLayout2 = uigridlayout(app.Panel);
            app.EditField_in1 = uieditfield(app.GridLayout2, 'numeric');
            app.EditField_in1.ValueChangedFcn = createCallbackFcn(app, @EditField_in1ValueChanged, true);
            app.EditField_in1.Value = 101.6;
            app.EditField_in2 = uieditfield(app.GridLayout2, 'numeric');
            app.EditField_in2.ValueChangedFcn = createCallbackFcn(app, @EditField_in2ValueChanged, true);
            app.EditField_in2.Value = 254;
            app.EditField_in3 = uieditfield(app.GridLayout2, 'numeric');
            app.EditField_in3.ValueChangedFcn = createCallbackFcn(app, @EditField_in3ValueChanged, true);
            app.EditField_in3.Value = 177.8;
            app.EditField_in4 = uieditfield(app.GridLayout2, 'numeric');
            app.EditField_in4.ValueChangedFcn = createCallbackFcn(app, @EditField_in4ValueChanged, true);
            app.EditField_in4.Value = 304.8;
            app.EditField_in5 = uieditfield(app.GridLayout2, 'numeric');
            app.EditField_in5.ValueChangedFcn = createCallbackFcn(app, @EditField_in5ValueChanged, true);
            app.EditField_in5.Value = 250;
            app.EditField_in6 = uieditfield(app.GridLayout2, 'numeric');
            app.EditField_in6.ValueChangedFcn = createCallbackFcn(app, @EditField_in6ValueChanged, true);
            app.dwBQLabel_in1 = uilabel(app.GridLayout2);
            app.dwBQLabel_in1.Text = 'mm';
            app.dwBQLabel_in2 = uilabel(app.GridLayout2);
            app.dwBQLabel_in2.Text = 'mm';
            app.dwBQLabel_in3 = uilabel(app.GridLayout2);
            app.dwBQLabel_in3.Text = 'mm';
            app.dwBQLabel_in4 = uilabel(app.GridLayout2);
            app.dwBQLabel_in4.Text = 'mm';
            app.dwBQLabel_in5 = uilabel(app.GridLayout2);
            app.dwBQLabel_in5.Text = 'rad/s';
            app.dwBQLabel_in6 = uilabel(app.GridLayout2);
            app.dwBQLabel_in6.Text = 'rad/s²';
            app.BQLabel_in1 = uilabel(app.GridLayout2);
            app.BQLabel_in1.Text = {'曲柄AB长度'; ''};
            app.BQLabel_in2 = uilabel(app.GridLayout2);
            app.BQLabel_in2.Text = '连杆BC长度';
            app.BQLabel_in3 = uilabel(app.GridLayout2);
            app.BQLabel_in3.Text = '连架杆CD长度';
            app.BQLabel_in4 = uilabel(app.GridLayout2);
            app.BQLabel_in4.Text = '机架AD长度';
            app.BQLabel_in5 = uilabel(app.GridLayout2);
            app.BQLabel_in5.Text = '曲柄转速ω1';
            app.BQLabel_in6 = uilabel(app.GridLayout2);
            app.BQLabel_in6.Text = '曲柄转动加速度a';
            app.BQLabel_in7 = uilabel(app.GridLayout2);
            app.BQLabel_in7.Text = '曲柄起角Φ';
            app.EditField_in7 = uieditfield(app.GridLayout2, 'numeric');
            app.EditField_in7.ValueChangedFcn = createCallbackFcn(app, @EditField_in7ValueChanged, true);
            app.EditField_in7.Value = 70;
            app.dwBQLabel_in7 = uilabel(app.GridLayout2);
            app.dwBQLabel_in7.Text = '°';
            app.Panel_2 = uipanel(app.GridLayout);
            app.Panel_2.Title = '输出参数';
            app.GridLayout3 = uigridlayout(app.Panel_2);
            app.EditField_out1 = uieditfield(app.GridLayout3, 'numeric');
            app.EditField_out1.ValueDisplayFormat = '%.2f';
            app.EditField_out1.Editable = 'off';
            app.EditField_out2 = uieditfield(app.GridLayout3, 'numeric');
            app.EditField_out2.ValueDisplayFormat = '%.2f';
            app.EditField_out2.Editable = 'off';
            app.EditField_out3 = uieditfield(app.GridLayout3, 'numeric');
            app.EditField_out3.ValueDisplayFormat = '%.2f';
            app.EditField_out3.Editable = 'off';
            app.EditField_out4 = uieditfield(app.GridLayout3, 'numeric');
            app.EditField_out4.ValueDisplayFormat = '%.2f';
            app.EditField_out4.Editable = 'off';
            app.EditField_out5 = uieditfield(app.GridLayout3, 'numeric');
            app.EditField_out5.ValueDisplayFormat = '%.2f';
            app.EditField_out5.Editable = 'off';
            app.EditField_out6 = uieditfield(app.GridLayout3, 'numeric');
            app.EditField_out6.ValueDisplayFormat = '%.2f';
            app.EditField_out6.Editable = 'off';
            app.EditField_out8 = uieditfield(app.GridLayout3, 'numeric');
            app.EditField_out8.ValueDisplayFormat = '%.2f';
            app.EditField_out8.Editable = 'off';
            app.EditField_out9 = uieditfield(app.GridLayout3, 'numeric');
            app.EditField_out9.ValueDisplayFormat = '%.2f';
            app.EditField_out9.Editable = 'off';
            app.EditField_out11 = uieditfield(app.GridLayout3, 'numeric');
            app.EditField_out11.ValueDisplayFormat = '%.2f';
            app.EditField_out11.Editable = 'off';
            app.EditField_out12 = uieditfield(app.GridLayout3, 'numeric');
            app.EditField_out12.ValueDisplayFormat = '%.2f';
            app.EditField_out12.Editable = 'off';
            app.dwBQLabel_out1 = uilabel(app.GridLayout3);
            app.dwBQLabel_out1.Text = 'rad';
            app.dwBQLabel_out4 = uilabel(app.GridLayout3);
            app.dwBQLabel_out4.Text = 'rad';
            app.dwBQLabel_out2 = uilabel(app.GridLayout3);
            app.dwBQLabel_out2.Text = 'rad/s';
            app.dwBQLabel_out3 = uilabel(app.GridLayout3);
            app.dwBQLabel_out3.Text = 'rad/s²';
            app.dwBQLabel_out5 = uilabel(app.GridLayout3);
            app.dwBQLabel_out5.Text = 'rad/s';
            app.dwBQLabel_out6 = uilabel(app.GridLayout3);
            app.dwBQLabel_out6.Text = 'rad/s²';
            app.dwBQLabel_out8 = uilabel(app.GridLayout3);
            app.dwBQLabel_out8.Text = 'mm/s';
            app.dwBQLabel_out9 = uilabel(app.GridLayout3);
            app.dwBQLabel_out9.Text = 'mm/s²';
            app.dwBQLabel_out11 = uilabel(app.GridLayout3);
            app.dwBQLabel_out11.Text = 'mm/s';
            app.dwBQLabel_out12 = uilabel(app.GridLayout3);
            app.dwBQLabel_out12.Text = 'mm/s²';
            app.BQLabel_out1 = uilabel(app.GridLayout3);
            app.BQLabel_out1.Text = '连杆角位移';
            app.BQLabel_out2 = uilabel(app.GridLayout3);
            app.BQLabel_out2.Text = '连杆角速度';
            app.BQLabel_out3 = uilabel(app.GridLayout3);
            app.BQLabel_out3.Text = '连杆角加速度';
            app.BQLabel_out4 = uilabel(app.GridLayout3);
            app.BQLabel_out4.Text = '连架杆角位移';
            app.BQLabel_out5 = uilabel(app.GridLayout3);
            app.BQLabel_out5.Text = '连架杆角速度';
            app.BQLabel_out6 = uilabel(app.GridLayout3);
            app.BQLabel_out6.Text = '连架杆角加速度';
            app.BQLabel_out7 = uilabel(app.GridLayout3);
            app.BQLabel_out7.Text = 'B点：';
            app.BQLabel_out8 = uilabel(app.GridLayout3);
            app.BQLabel_out8.Text = 'B点速度';
            app.BQLabel_out9 = uilabel(app.GridLayout3);
            app.BQLabel_out9.Text = 'B点加速度';
            app.BQLabel_out10 = uilabel(app.GridLayout3);
            app.BQLabel_out10.Text = 'C点：';
            app.BQLabel_out11 = uilabel(app.GridLayout3);
            app.BQLabel_out11.Text = 'C点速度';
            app.BQLabel_out12 = uilabel(app.GridLayout3);
            app.BQLabel_out12.Text = 'C点加速度';
            app.EditField_out7 = uieditfield(app.GridLayout3, 'numeric');
            app.EditField_out7.Editable = 'off';
            app.EditField_out7.Visible = 'off';
            app.dwBQLabel_out7 = uilabel(app.GridLayout3);
            app.dwBQLabel_out7.Visible = 'off';
            app.dwBQLabel_out7.Text = '';
            app.EditField_out10 = uieditfield(app.GridLayout3, 'numeric');
            app.EditField_out10.Editable = 'off';
            app.EditField_out10.Visible = 'off';
            app.dwBQLabel_out10 = uilabel(app.GridLayout3);
            app.dwBQLabel_out10.Visible = 'off';
            app.dwBQLabel_out10.Text = '';
            app.StartButton = uibutton(app.GridLayout, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = '开始';
            app.StopButton = uibutton(app.GridLayout, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = '结束';
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = '机构选择';
            app.DropDown = uidropdown(app.GridLayout);
            app.DropDown.Items = {'                  四杆机构', '              曲柄滑块机构', '                  凸轮机构', '                  齿轮机构'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = '                  四杆机构';
            app.saveButton = uibutton(app.GridLayout, 'push');
            app.saveButton.ButtonPushedFcn = createCallbackFcn(app, @saveButtonPushed, true);
            app.saveButton.Text = 'save';
            app.Switch = uiswitch(app.GridLayout, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Button = uibutton(app.GridLayout, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '正逆运动学';
            app.TipsLabel = uilabel(app.GridLayout);
            app.TipsLabel.Text = '【Tips】：';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            % Assign app.ContextMenu
            app.Tab_41.ContextMenu = app.ContextMenu;
            app.AMenu = uimenu(app.ContextMenu);
            app.AMenu.Text = '支点A';
            app.BMenu = uimenu(app.ContextMenu);
            app.BMenu.Text = '支点B';
            app.CMenu = uimenu(app.ContextMenu);
            app.CMenu.Text = '支点C';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Machine_mian

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end