classdef drawpoint < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        GridLayout         matlab.ui.container.GridLayout
        UIAxes             matlab.ui.control.UIAxes
        startButton        matlab.ui.control.Button
        TipsLabel          matlab.ui.control.Label
        Label              matlab.ui.control.Label
        cleanButton        matlab.ui.control.Button
        returnButton       matlab.ui.control.Button
        xEditFieldLabel    matlab.ui.control.Label
        xEditField_1       matlab.ui.control.NumericEditField
        yEditFieldLabel    matlab.ui.control.Label
        yEditField_1       matlab.ui.control.NumericEditField
        xEditField_2Label  matlab.ui.control.Label
        xEditField_2       matlab.ui.control.NumericEditField
        yEditField_2Label  matlab.ui.control.Label
        yEditField_2       matlab.ui.control.NumericEditField
        xEditField_3Label  matlab.ui.control.Label
        xEditField_3       matlab.ui.control.NumericEditField
        yEditField_3Label  matlab.ui.control.Label
        yEditField_3       matlab.ui.control.NumericEditField
        xEditField_4Label  matlab.ui.control.Label
        xEditField_4       matlab.ui.control.NumericEditField
        yEditField_4Label  matlab.ui.control.Label
        yEditField_4       matlab.ui.control.NumericEditField
        Button             matlab.ui.control.Button
        ContextMenu        matlab.ui.container.ContextMenu
        Menu               matlab.ui.container.Menu
        Menu_2             matlab.ui.container.Menu
    end

    
    properties (Access = private)
        CallingApp % Description
        x = [];
        y = []; % Description
        exit_sign = 0; % 退出绘画
    end
    
    methods (Access = private)


        function plotpos(app)
            plot(app.UIAxes,[app.x, app.x(1)], [app.y, app.y(1)]);
            hold(app.UIAxes,"on");
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.CallingApp.TipsLabel.Text = "Tips:正在调动图形绘制窗口";
            app.CallingApp = mainapp;
            app.TipsLabel.Text = "请选择起始点O1";
        end

        % Button pushed function: startButton
        function startButtonPushed(app, event)
            %drawing_app(app);
            drawpos(app);
        end

        % Window button motion function: UIFigure
        function UIFigureWindowButtonMotion(app, event)
            axis(app.UIAxes,[-100 100 -100 100]);
            grid(app.UIAxes, 'on');
            p=get(app.UIAxes,'CurrentPoint') ;%获得句柄
            msgstr = sprintf('(%3.3f, %3.3f)',p(1,1),p(1,2)); %获得位置
%             plot(app.UIAxes, p(1,1), p(1,2), 'o');
            
            if (isempty(app.x) || isempty(app.y))
                
            elseif (app.exit_sign == 0)
                plot(app.UIAxes, p(1,1), p(1,2), 'x');
                plot(app.UIAxes, [app.x, p(1,1)], [app.y, p(1,2)]);
            end
            
            
            app.Label.Text = ["当前坐标：", msgstr];
            %drawpos(app, x, y);
            %uicontrol('style','text','position',[ 100 100 20],'string',msgstr);
            %app.position_app = positionApp(app, p(1), p(2));
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)

            delete(app);
            
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)

            axis(app.UIAxes,[-100 100 -100 100]);
            grid(app.UIAxes, 'on');
            
            p=get(app.UIAxes,'CurrentPoint') ;%获得句柄
            app.x = [app.x, p(1,1)];
            app.y = [app.y, p(1,2)];
            plot(app.UIAxes, app.x,app.y);
            if (isempty(app.x))
                app.TipsLabel.Text = "请选择起始点O1";
                
            elseif(length(app.x) == 1)
                app.xEditField_1.Value = p(1,1);
                app.yEditField_1.Value = p(1,2);
                app.TipsLabel.Text = "请选择第二个端点，完成线段L1";
                
            elseif(length(app.x) == 2)
                app.xEditField_2.Value = p(1,1);
                app.yEditField_2.Value = p(1,2);
                app.TipsLabel.Text = "请选择第三个端点，完成线段L2";
                
            elseif(length(app.x) == 3)
                app.xEditField_3.Value = p(1,1);
                app.yEditField_3.Value = p(1,2);
                app.TipsLabel.Text = "请选择第四个端点，完成线段L3";
                
            elseif(length(app.x) == 4)
                app.xEditField_4.Value = p(1,1);
                app.yEditField_4.Value = p(1,2);
                app.TipsLabel.Text = "已完成四杆机构绘制，如需撤回请点击clean";
                app.exit_sign = 1;
                plotpos(app);
            end
            
%             %plot(app.UIAxes, p(1,1), p(1,2), 'o');
%             if (strcmpi(get(app.UIFigure, 'SelectionType'),'normal'))
%                 disp("√");
%                 p=get(app.UIAxes,'CurrentPoint') ;%获得句柄               
%                 app.x = [app.x, p(1,1)];
%                 app.y = [app.y, p(1,2)];
%                 
%                 
%                 
%                 app.exit_sign = 0;
%                 %plot(app.UIAxes, app.x,app.y);
%             elseif (strcmpi(get(app.UIFigure, 'SelectionType'),'alt'))
%                 app.exit_sign = 1;
%                 plotpos(app);
%             end
            
            
            disp("×");

        end

        % Button pushed function: cleanButton
        function cleanButtonPushed(app, event)
            cla(app.UIAxes,'reset');
            %clf(app.UIAxes,'reset');
            app.x = [];
            app.y = [];
            app.xEditField_1.Value = 0;
            app.yEditField_1.Value = 0;
            app.xEditField_2.Value = 0;
            app.yEditField_2.Value = 0;
            app.xEditField_3.Value = 0;
            app.yEditField_3.Value = 0;
            app.xEditField_4.Value = 0;
            app.yEditField_4.Value = 0;
            app.exit_sign = 0;
            app.TipsLabel.Text = "请选择起始点O1";
        end

        % Button pushed function: returnButton
        function returnButtonPushed(app, event)
            L1 = sqrt((app.x(1) - app.x(2)) * (app.x(1) - app.x(2)) + (app.y(1) - app.y(2)) * (app.y(1) - app.y(2)));
            L2 = sqrt((app.x(2) - app.x(3)) * (app.x(2) - app.x(3)) + (app.y(2) - app.y(3)) * (app.y(2) - app.y(3)));
            L3 = sqrt((app.x(3) - app.x(4)) * (app.x(3) - app.x(4)) + (app.y(3) - app.y(4)) * (app.y(3) - app.y(4)));
            L4 = sqrt((app.x(4) - app.x(1)) * (app.x(4) - app.x(1)) + (app.y(4) - app.y(1)) * (app.y(4) - app.y(1)));
            app.CallingApp.l1 = L1;
            app.CallingApp.l1 = L2;
            app.CallingApp.l1 = L3;
            app.CallingApp.l1 = L4;
            %app.CallingApp.StartButtonPushed(app, event);
        end

        % Value changed function: xEditField_1
        function xEditField_1ValueChanged(app, event)
            value = app.xEditField_1.Value;
            app.x(1) = value;
        end

        % Value changed function: yEditField_1
        function yEditField_1ValueChanged(app, event)
            value = app.yEditField_1.Value;
            app.y(1) = value;
        end

        % Value changed function: xEditField_2
        function xEditField_2ValueChanged(app, event)
            value = app.xEditField_2.Value;
            app.x(2) = value;
        end

        % Value changed function: yEditField_2
        function yEditField_2ValueChanged(app, event)
            value = app.yEditField_2.Value;
            app.y(2) = value;
        end

        % Value changed function: xEditField_3
        function xEditField_3ValueChanged(app, event)
            value = app.xEditField_3.Value;
            app.x(3) = value;
        end

        % Value changed function: yEditField_3
        function yEditField_3ValueChanged(app, event)
            value = app.yEditField_3.Value;
            app.y(3) = value;
        end

        % Value changed function: xEditField_4
        function xEditField_4ValueChanged(app, event)
            value = app.xEditField_4.Value;
            app.x(4) = value;
        end

        % Value changed function: yEditField_4
        function yEditField_4ValueChanged(app, event)
            value = app.yEditField_4.Value;
            app.y(4) = value;
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            cla(app.UIAxes,'reset');
            plotpos(app);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButtonMotion, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.startButton = uibutton(app.GridLayout, 'push');
            app.startButton.ButtonPushedFcn = createCallbackFcn(app, @startButtonPushed, true);
            app.startButton.Text = 'start';
            app.TipsLabel = uilabel(app.GridLayout);
            app.TipsLabel.Text = 'Tips:';
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = '当前坐标：';
            app.cleanButton = uibutton(app.GridLayout, 'push');
            app.cleanButton.ButtonPushedFcn = createCallbackFcn(app, @cleanButtonPushed, true);
            app.cleanButton.Text = 'clean';
            app.returnButton = uibutton(app.GridLayout, 'push');
            app.returnButton.ButtonPushedFcn = createCallbackFcn(app, @returnButtonPushed, true);
            app.returnButton.Text = 'return';
            app.xEditFieldLabel = uilabel(app.GridLayout);
            app.xEditFieldLabel.Text = '坐标1x';
            app.xEditField_1 = uieditfield(app.GridLayout, 'numeric');
            app.xEditField_1.ValueChangedFcn = createCallbackFcn(app, @xEditField_1ValueChanged, true);
            app.yEditFieldLabel = uilabel(app.GridLayout);
            app.yEditFieldLabel.Text = '坐标1y';
            app.yEditField_1 = uieditfield(app.GridLayout, 'numeric');
            app.yEditField_1.ValueChangedFcn = createCallbackFcn(app, @yEditField_1ValueChanged, true);
            app.xEditField_2Label = uilabel(app.GridLayout);
            app.xEditField_2Label.Text = '坐标2x';
            app.xEditField_2 = uieditfield(app.GridLayout, 'numeric');
            app.xEditField_2.ValueChangedFcn = createCallbackFcn(app, @xEditField_2ValueChanged, true);
            app.yEditField_2Label = uilabel(app.GridLayout);
            app.yEditField_2Label.Text = '坐标2y';
            app.yEditField_2 = uieditfield(app.GridLayout, 'numeric');
            app.yEditField_2.ValueChangedFcn = createCallbackFcn(app, @yEditField_2ValueChanged, true);
            app.xEditField_3Label = uilabel(app.GridLayout);
            app.xEditField_3Label.Text = '坐标3x';
            app.xEditField_3 = uieditfield(app.GridLayout, 'numeric');
            app.xEditField_3.ValueChangedFcn = createCallbackFcn(app, @xEditField_3ValueChanged, true);
            app.yEditField_3Label = uilabel(app.GridLayout);
            app.yEditField_3Label.Text = '坐标3y';
            app.yEditField_3 = uieditfield(app.GridLayout, 'numeric');
            app.yEditField_3.ValueChangedFcn = createCallbackFcn(app, @yEditField_3ValueChanged, true);
            app.xEditField_4Label = uilabel(app.GridLayout);
            app.xEditField_4Label.Text = '坐标4x';
            app.xEditField_4 = uieditfield(app.GridLayout, 'numeric');
            app.xEditField_4.ValueChangedFcn = createCallbackFcn(app, @xEditField_4ValueChanged, true);
            app.yEditField_4Label = uilabel(app.GridLayout);
            app.yEditField_4Label.Text = '坐标4y';
            app.yEditField_4 = uieditfield(app.GridLayout, 'numeric');
            app.yEditField_4.ValueChangedFcn = createCallbackFcn(app, @yEditField_4ValueChanged, true);
            app.Button = uibutton(app.GridLayout, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '刷新';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            % Assign app.ContextMenu
            app.startButton.ContextMenu = app.ContextMenu;
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = '四杆机构';
            app.Menu_2 = uimenu(app.ContextMenu);
            app.Menu_2.Text = '曲柄摇杆机构';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = drawpoint(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end