classdef zhengni < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        GridLayout      matlab.ui.container.GridLayout
        UIAxes_1        matlab.ui.control.UIAxes
        UIAxes_3        matlab.ui.control.UIAxes
        UIAxes_2        matlab.ui.control.UIAxes
        exitButton      matlab.ui.control.Button
        Switch          matlab.ui.control.Switch
        StartButton_2   matlab.ui.control.Button
        Image           matlab.ui.control.Image
        Image2          matlab.ui.control.Image
        TsSliderLabel   matlab.ui.control.Label
        TsSlider        matlab.ui.control.Slider
        faiSliderLabel  matlab.ui.control.Label
        faiSlider       matlab.ui.control.Slider
        xsSliderLabel   matlab.ui.control.Label
        xsSlider        matlab.ui.control.Slider
        xfSliderLabel   matlab.ui.control.Label
        xfSlider        matlab.ui.control.Slider
        hSliderLabel    matlab.ui.control.Label
        hSlider         matlab.ui.control.Slider
        zsSliderLabel   matlab.ui.control.Label
        zsSlider        matlab.ui.control.Slider
    end

    
    properties (Access = private)
        %主窗口
        CallingApp
        stop_sign = false % Description
        Ts = 1;
        fai = 0.5;
        xs = -20;
        xf = 20;
        zs = 0;
        h = 30; % Description
    end
    
    methods (Access = private)
        
        function baixian(app)
            %clc;
            %clear;
%             Ts = 1;
%             fai = 0.5;
%             xs = -20;
%             xf = 20;
%             zs = 0;
%             h = 30;
            
            x = [];
            z = [];
            
            for t = 0:0.01:app.Ts*app.fai
                
                sigma = 2 * pi * t / (app.fai * app.Ts);
                xep = (app.xf - app.xs) * ((sigma - sin(sigma)) / (2 * pi)) + app.xs;
                zep = app.h * (1-cos(sigma)) / 2 + app.zs;
                x = [x, xep];
                z = [z, zep];
                
                %     plot(xep,zep,'o','MarkerFaceColor','red');
                %     hold on;
                %     %axis manual;
                %     pause(0.5);
                %     drawnow;
                %     xep;
                %     zep;
                
                
            end
            plot(app.UIAxes_2, x, z);
            hold(app.UIAxes_2, "on");
            for k = 1:51
                %axis(app.UIAxes_1, [-80 80 -100 0]);
                plot(app.UIAxes_2, x(k),z(k),'o','MarkerFaceColor','red');
                drawnow;
                %pause(0.1);
            end
        end
        
        function kaihaun(app)
            
%             app.Ts = 1;
%             fai = 0.5;
%             app.xs = -20;
%             xf = 20;
%             zs = 0;
%             h = 20;
            
            l1 = 80;
            l2 = 60;
            joint1x = zeros(1, 51);
            joint1y = zeros(1, 51);
            joint2x = zeros(1, 51);
            joint2y = zeros(1, 51);
            [x, z] = zuobiao(app, app.Ts, app.fai, app.xs, app.xf, app.zs, app.h);
            plot(app.UIAxes_1, x1, z1);
            hold(app.UIAxes_1, "on");
            theta2 = zeros(1, 60);
            theta1 = zeros(1, 60);
            for count = 1:3
                if app.stop_sign
                    app.stop_sign = false;
                    break;
                end
                for k = 1:51
                    cla(app.UIAxes_1);
                    % kaihaun
                    axis(app.UIAxes_1, [-80 80 -120 50]);
                    plot(app.UIAxes_1, x(k),z(k),'o','MarkerFaceColor','red');
                    
                    theta2(k) = 180 - acosd((x(k)^2 + z(k)^2 - l1^2 - l2^2)/(-2*l1*l2));
                    theta1(k) = atand(z1(k)/x(k)) - acosd((x(k)^2 + z(k)^2 + l1^2 - l2^2)/(2*l1*sqrt(x(k)^2 + z(k)^2)));
                    if theta1(k) < 0
                        theta1(k) = theta1(k) + 180;
                    end
                    joint1x(k) = -l1 * cosd(theta1(k));
                    joint1y(k) = -l1 *sind(theta1(k));
                    joint2x(k) = joint1x(k) + l2 * cosd(180 - theta1(k) - theta2(k));
                    joint2y(k) = joint1y(k) - l2 * sind(180 - theta1(k) - theta2(k));
                    plot(app.UIAxes_1, [-20 20], [0 0], 'LineWidth',5, 'Color', [0.6350 0.0780 0.1840]);
                    plot(app.UIAxes_1, joint1x(k), joint1y(k), 'o');
                    plot(app.UIAxes_1, joint2x(k), joint2y(k), 'o');
                    plot(app.UIAxes_1, [0 joint1x(k) x(k)], [0 joint1y(k) z(k)]);
                    %pause(0.2);
                    
                    %bihaun
                    
                    drawnow;
                end
                xx = linspace(20, -20, 51);
                zz = -120;
                for k = 1 : 51
                    cla(app.UIAxes_1);
                    theta2(k) = 180 - acosd((xx(k)^2 + zz^2 - l1^2 - l2^2)/(-2*l1*l2));
                    theta1(k) = atand(zz/xx(k)) - acosd((xx(k)^2 + zz^2 + l1^2 - l2^2)/(2*l1*sqrt(xx(k)^2 + zz^2)));
                    if theta1(k) < 0
                        theta1(k) = theta1(k) + 180;
                    end
                    joint1x(k) = -l1 * cosd(theta1(k));
                    joint1y(k) = -l1 *sind(theta1(k));
                    joint2x(k) = joint1x(k) + l2 * cosd(180 - theta1(k) - theta2(k));
                    joint2y(k) = joint1y(k) - l2 * sind(180 - theta1(k) - theta2(k));
                    axis(app.UIAxes_1, [-80 80 -120 50]);
                    plot(app.UIAxes_1, xx(k),zz,'o','MarkerFaceColor','red');
                    plot(app.UIAxes_1, [-20 20], [0 0], 'LineWidth',5, 'Color', [0.6350 0.0780 0.1840]);
                    plot(app.UIAxes_1, joint1x(k), joint1y(k), 'o');
                    plot(app.UIAxes_1, joint2x(k), joint2y(k), 'o');
                    plot(app.UIAxes_1, [0 joint1x(k) joint2x(k)], [0 joint1y(k) joint2y(k)]);
                    %pause(0.2);
                    drawnow;
                end
                
            end


        end
        
        function [x, z] = zuobiao(~, Ts, fai, xs, xf, zs, h)
            %myFun - Description
            %
            % Syntax: [x, y] = myFun(input)
            %
            % Long description
            x = [];
            z = [];
            
            for t = 0:0.01:Ts*fai
                
                sigma = 2 * pi * t / (fai * Ts);
                xep = (xf - xs) * ((sigma - sin(sigma)) / (2 * pi)) + xs;
                zep = h * (1-cos(sigma)) / 2 + zs;
                x = [x, xep];
                z = [z, zep-120];
                
            end
            
        end
        
        function bihaun(app)
            
%             Ts = 1;
%             fai = 0.5;
%             xs = -20;
%             xf = 20;
%             zs = 0;
%             h = 20;
            
            l1 = 60;
            l2 = 80;
            joint1x = zeros(1, 51);
            joint1y = zeros(1, 51);
            joint2x = zeros(1, 51);
            joint2y = zeros(1, 51);
            [x, z] = zuobiao(app, app.Ts, app.fai, app.xs, app.xf, app.zs, app.h);
            plot(app.UIAxes_3, x, z);
            hold(app.UIAxes_3, "on");
            theta2 = zeros(1, 60);
            theta1 = zeros(1, 60);
            
            for count = 1:3
                for k = 1:51
                    l(k) = sqrt(x(k)^2 + z(k)^2);
                    duichen(k) = 2*acosd((l1^2 + l(k)^2 - l2^2)/(2*l1*l(k)));
                end
                for k = 1:51
                    cla(app.UIAxes_3);
                    axis([-80 80 -120 50]);
                    plot(app.UIAxes_3, x(k),z(k),'o','MarkerFaceColor','red');
                    
                    theta2(k) = 180 - acosd((x(k)^2 + z(k)^2 - l1^2 - l2^2)/(-2*l1*l2));
                    theta1(k) = atand(z(k)/x(k)) - acosd((x(k)^2 + z(k)^2 + l1^2 - l2^2)/(2*l1*sqrt(x(k)^2 + z(k)^2)));
                    if theta1(k) < 0
                        theta1(k) = theta1(k) + 180;
                    end
                    joint1x(k) = -l1 * cosd(theta1(k));
                    joint1y(k) = -l1 *sind(theta1(k));
                    joint2x(k) = joint1x(k) + l2 * cosd(180 - theta1(k) - theta2(k));
                    joint2y(k) = joint1y(k) - l2 * sind(180 - theta1(k) - theta2(k));
                    joint3x(k) = -l1 * cosd(theta1(k) + duichen(k));
                    joint3y(k) = -l1 *sind(theta1(k) + duichen(k));
                    plot(app.UIAxes_3, [-20 20], [0 0], 'LineWidth',5, 'Color', [0.6350 0.0780 0.1840]);
                    plot(app.UIAxes_3, joint1x(k), joint1y(k), 'o');
                    plot(app.UIAxes_3, joint2x(k), joint2y(k), 'o');
                    plot(app.UIAxes_3, joint3x(k), joint3y(k), 'o');
                    plot(app.UIAxes_3, [0 joint1x(k) x(k) joint3x(k) 0], [0 joint1y(k) z(k) joint3y(k) 0]);
                    %pause(0.2);
                    drawnow;
                end
                xx = linspace(20, -20, 51);
                zz = -120;
                for k = 1:51
                    l(k) = sqrt(xx(k)^2 + zz^2);
                    duichen(k) = 2*acosd((l1^2 + l(k)^2 - l2^2)/(2*l1*l(k)));
                end
                for k = 1 : 51
                    cla(app.UIAxes_3);
                    theta2(k) = 180 - acosd((xx(k)^2 + zz^2 - l1^2 - l2^2)/(-2*l1*l2));
                    theta1(k) = atand(zz/xx(k)) - acosd((xx(k)^2 + zz^2 + l1^2 - l2^2)/(2*l1*sqrt(xx(k)^2 + zz^2)));
                    if theta1(k) < 0
                        theta1(k) = theta1(k) + 180;
                    end
                    joint1x(k) = -l1 * cosd(theta1(k));
                    joint1y(k) = -l1 *sind(theta1(k));
                    joint2x(k) = joint1x(k) + l2 * cosd(180 - theta1(k) - theta2(k));
                    joint2y(k) = joint1y(k) - l2 * sind(180 - theta1(k) - theta2(k));
                    joint3x(k) = -l1 * cosd(theta1(k) + duichen(k));
                    joint3y(k) = -l1 * sind(theta1(k) + duichen(k));
                    axis(app.UIAxes_3, [-80 80 -120 50]);
                    plot(app.UIAxes_3, xx(k),zz,'o','MarkerFaceColor','red');
                    plot(app.UIAxes_3, [-20 20], [0 0], 'LineWidth',5, 'Color', [0.6350 0.0780 0.1840]);
                    plot(app.UIAxes_3, joint1x(k), joint1y(k), 'o');
                    plot(app.UIAxes_3, joint2x(k), joint2y(k), 'o');
                    plot(app.UIAxes_3, joint3x(k), joint3y(k), 'o');
                    plot(app.UIAxes_3, [0 joint1x(k) xx(k) joint3x(k) 0], [0 joint1y(k) zz joint3y(k) 0]);
                    %pause(0.2);
                    drawnow;
                end
                
            end


            %[theta1, theta2] = calculate(l1, l2, x, y);
            
            % joint1x = zeros(1, 51);
            % joint1y = zeros(1, 51);
            % joint2x = zeros(1, 51);
            % joint2y = zeros(1, 51);
            
            % for t = 1:51
            %     clf;
            %     hold on;
            %     joint1x(t) = -l1 * cosd(theta1(t));
            %     joint1y(t) = -l1 *sind(theta1(t));
            %     joint2x(t) = joint1x(t) + l2 * cosd(theta1(t) + theta2(t));
            %     joint2y(t) = joint1y(t) - l2 * sind(theta1(t) + theta2(t));
            %     plot(0, 0, 'o','MarkerFaceColor','red');
            %     plot(joint1x(t), joint1y(t), 'o');
            %     plot(joint2x(t), joint2y(t), 'o');
            %     plot([0 joint1x(t) joint2x(t)], [0 joint1y(t) joint2y(t)]);
            %     axis([-80 80 -100 0]);
            %     drawnow;
            %     pause(0.1);
            % end
        end
        
        function meantime(app)
%             Ts = 1;
%             fai = 0.5;
%             xs = -20;
%             xf = 20;
%             zs = 0;
%             h = 20;
            
            l1 = 80;
            l2 = 60;
            l3 = 60;
            l4 = 80;
            joint1x = zeros(1, 51);
            joint1y = zeros(1, 51);
            joint2x = zeros(1, 51);
            joint2y = zeros(1, 51);
            [x, z] = zuobiao(app, app.Ts, app.fai, app.xs, app.xf, app.zs, app.h);
            %kaihaun
            plot(app.UIAxes_1, x, z);
            hold(app.UIAxes_1, "on");
            %bihuan
            plot(app.UIAxes_3, x, z);
            hold(app.UIAxes_3, "on");
            
            theta2 = zeros(1, 60);
            theta1 = zeros(1, 60);
            
            for count = 1:1000
                if app.stop_sign
                    app.stop_sign = false;
                    break;
                end
                for k = 1:51
                    if app.stop_sign
                        %app.stop_sign = false;
                        break;
                    end
                    %kaihaun
                    cla(app.UIAxes_1);
                    axis(app.UIAxes_1, [-80 80 -120 50]);
                    plot(app.UIAxes_1, x(k),z(k),'o','MarkerFaceColor','red');
                    theta2(k) = 180 - acosd((x(k)^2 + z(k)^2 - l1^2 - l2^2)/(-2*l1*l2));
                    theta1(k) = atand(z(k)/x(k)) - acosd((x(k)^2 + z(k)^2 + l1^2 - l2^2)/(2*l1*sqrt(x(k)^2 + z(k)^2)));
                    if theta1(k) < 0
                        theta1(k) = theta1(k) + 180;
                    end
                    joint1x(k) = -l1 * cosd(theta1(k));
                    joint1y(k) = -l1 *sind(theta1(k));
                    joint2x(k) = joint1x(k) + l2 * cosd(180 - theta1(k) - theta2(k));
                    joint2y(k) = joint1y(k) - l2 * sind(180 - theta1(k) - theta2(k));
                    plot(app.UIAxes_1, [-20 20], [0 0], 'LineWidth',5, 'Color', [0.6350 0.0780 0.1840]);
                    plot(app.UIAxes_1, joint1x(k), joint1y(k), 'o');
                    plot(app.UIAxes_1, joint2x(k), joint2y(k), 'o');
                    plot(app.UIAxes_1, [0 joint1x(k) x(k)], [0 joint1y(k) z(k)]);
                    %pause(0.2);
                    
                    %bihaun
                    l(k) = sqrt(x(k)^2 + z(k)^2);
                    duichen(k) = 2*acosd((l3^2 + l(k)^2 - l4^2)/(2*l3*l(k)));
                    cla(app.UIAxes_3);
                    axis(app.UIAxes_3, [-80 80 -120 50]);
                    plot(app.UIAxes_3, x(k), z(k),'o','MarkerFaceColor','red');   
                    theta2(k) = 180 - acosd((x(k)^2 + z(k)^2 - l3^2 - l4^2)/(-2*l3*l4));
                    theta1(k) = atand(z(k)/x(k)) - acosd((x(k)^2 + z(k)^2 + l3^2 - l4^2)/(2*l3*sqrt(x(k)^2 + z(k)^2)));
                    if theta1(k) < 0
                        theta1(k) = theta1(k) + 180;
                    end
                    joint1x(k) = -l3 * cosd(theta1(k));
                    joint1y(k) = -l3 *sind(theta1(k));
                    joint2x(k) = joint1x(k) + l3 * cosd(180 - theta1(k) - theta2(k));
                    joint2y(k) = joint1y(k) - l3 * sind(180 - theta1(k) - theta2(k));
                    joint3x(k) = -l3 * cosd(theta1(k) + duichen(k));
                    joint3y(k) = -l3 *sind(theta1(k) + duichen(k));
                    plot(app.UIAxes_3, [-20 20], [0 0], 'LineWidth',5, 'Color', [0.6350 0.0780 0.1840]);
                    plot(app.UIAxes_3, joint1x(k), joint1y(k), 'o');
                    %plot(app.UIAxes_3, joint2x(k), joint2y(k), 'o');
                    plot(app.UIAxes_3, joint3x(k), joint3y(k), 'o');
                    plot(app.UIAxes_3, [0 joint1x(k) x(k) joint3x(k) 0], [0 joint1y(k) z(k) joint3y(k) 0]);
                    %pause(0.2);
                    drawnow;

                end
                xx = linspace(20, -20, 51);
                zz = -120;
                for k = 1 : 51
                    if app.stop_sign
                        %app.stop_sign = false;
                        break;
                    end
                    %kaihaun
                    cla(app.UIAxes_1);
                    theta2(k) = 180 - acosd((xx(k)^2 + zz^2 - l1^2 - l2^2)/(-2*l1*l2));
                    theta1(k) = atand(zz/xx(k)) - acosd((xx(k)^2 + zz^2 + l1^2 - l2^2)/(2*l1*sqrt(xx(k)^2 + zz^2)));
                    if theta1(k) < 0
                        theta1(k) = theta1(k) + 180;
                    end
                    joint1x(k) = -l1 * cosd(theta1(k));
                    joint1y(k) = -l1 *sind(theta1(k));
                    joint2x(k) = joint1x(k) + l2 * cosd(180 - theta1(k) - theta2(k));
                    joint2y(k) = joint1y(k) - l2 * sind(180 - theta1(k) - theta2(k));
                    axis(app.UIAxes_1, [-80 80 -120 50]);
                    plot(app.UIAxes_1, xx(k),zz,'o','MarkerFaceColor','red');
                    plot(app.UIAxes_1, [-20 20], [0 0], 'LineWidth',5, 'Color', [0.6350 0.0780 0.1840]);
                    plot(app.UIAxes_1, joint1x(k), joint1y(k), 'o');
                    plot(app.UIAxes_1, joint2x(k), joint2y(k), 'o');
                    plot(app.UIAxes_1, [0 joint1x(k) joint2x(k)], [0 joint1y(k) joint2y(k)]);
                    %pause(0.2);

                    %bihuan
                    l(k) = sqrt(xx(k)^2 + zz^2);
                    duichen(k) = 2*acosd((l3^2 + l(k)^2 - l4^2)/(2*l3*l(k)));
                    cla(app.UIAxes_3);
                    theta2(k) = 180 - acosd((xx(k)^2 + zz^2 - l3^2 - l4^2)/(-2*l3*l4));
                    theta1(k) = atand(zz/xx(k)) - acosd((xx(k)^2 + zz^2 + l3^2 - l4^2)/(2*l3*sqrt(xx(k)^2 + zz^2)));
                    if theta1(k) < 0
                        theta1(k) = theta1(k) + 180;
                    end
                    joint1x(k) = -l3 * cosd(theta1(k));
                    joint1y(k) = -l3 *sind(theta1(k));
                    joint2x(k) = joint1x(k) + l4 * cosd(180 - theta1(k) - theta2(k));
                    joint2y(k) = joint1y(k) - l4 * sind(180 - theta1(k) - theta2(k));
                    joint3x(k) = -l3 * cosd(theta1(k) + duichen(k));
                    joint3y(k) = -l3 * sind(theta1(k) + duichen(k));
                    axis(app.UIAxes_3, [-80 80 -120 50]);
                    plot(app.UIAxes_3, xx(k),zz,'o','MarkerFaceColor','red');
                    plot(app.UIAxes_3, [-20 20], [0 0], 'LineWidth',5, 'Color', [0.6350 0.0780 0.1840]);
                    plot(app.UIAxes_3, joint1x(k), joint1y(k), 'o');
                    plot(app.UIAxes_3, joint2x(k), joint2y(k), 'o');
                    plot(app.UIAxes_3, joint3x(k), joint3y(k), 'o');
                    plot(app.UIAxes_3, [0 joint1x(k) xx(k) joint3x(k) 0], [0 joint1y(k) zz joint3y(k) 0]);
                    %pause(0.2);
                    drawnow;
                end
                
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.CallingApp.TipsLabel.Text = "Tips:正在调动正逆运动学窗口";
            app.CallingApp = mainapp;
            app.TsSlider.Value = app.Ts;
            app.faiSlider.Value = app.fai;
            app.xsSlider.Value = app.xs;
            app.xfSlider.Value = app.xf;
            app.hSlider.Value = app.h;
            app.zsSlider.Value = app.zs;
        end

        % Button pushed function: StartButton_2
        function StartButton_2Pushed(app, event)
            %摆线
            baixian(app);
            %开环+闭环腿
            meantime(app);


        end

        % Close request function: UIFigure
        function zhengniCloseRequest(app, event)
            app.CallingApp.Button.Enable = 'on';
            app.stop_sign = true;

            % Delete the dialog box
            delete(app)
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            if strcmpi(value, 'on')
                app.Switch.FontColor = [0.6350 0.0780 0.1840];
                app.Switch.FontWeight = 'bold';
                uiwait(app.UIFigure);
            elseif strcmpi(value, 'off')
                app.Switch.FontColor = [193/255 193/255 193/255];
                app.Switch.FontWeight = 'normal';
                uiresume(gcbf);
            end
        end

        % Button pushed function: exitButton
        function exitButtonPushed(app, event)
            app.stop_sign = true;
            %delete(app);
        end

        % Value changed function: faiSlider
        function faiSliderValueChanged(app, event)
            value = app.faiSlider.Value;
            app.fai = value;
        end

        % Value changed function: xsSlider
        function xsSliderValueChanged(app, event)
            value = app.xsSlider.Value;
            app.xs = value;
        end

        % Value changed function: xfSlider
        function xfSliderValueChanged(app, event)
            value = app.xfSlider.Value;
            app.xf = value;
        end

        % Value changed function: hSlider
        function hSliderValueChanged(app, event)
            value = app.hSlider.Value;
            app.h = value;
        end

        % Value changed function: zsSlider
        function zsSliderValueChanged(app, event)
            value = app.zsSlider.Value;
            app.zs = value;
        end

        % Value changed function: TsSlider
        function TsSliderValueChanged(app, event)
            value = app.TsSlider.Value;
            app.Ts = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @zhengniCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes_1 = uiaxes(app.GridLayout);
            title(app.UIAxes_1, '开环腿')
            xlabel(app.UIAxes_1, '')
            ylabel(app.UIAxes_1, '')
            app.UIAxes_3 = uiaxes(app.GridLayout);
            title(app.UIAxes_3, '闭环腿')
            xlabel(app.UIAxes_3, '')
            ylabel(app.UIAxes_3, '')
            app.UIAxes_2 = uiaxes(app.GridLayout);
            title(app.UIAxes_2, '摆线方程')
            xlabel(app.UIAxes_2, ' ')
            ylabel(app.UIAxes_2, '')
            app.exitButton = uibutton(app.GridLayout, 'push');
            app.exitButton.ButtonPushedFcn = createCallbackFcn(app, @exitButtonPushed, true);
            app.exitButton.Text = 'exit';
            app.Switch = uiswitch(app.GridLayout, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.StartButton_2 = uibutton(app.GridLayout, 'push');
            app.StartButton_2.ButtonPushedFcn = createCallbackFcn(app, @StartButton_2Pushed, true);
            app.StartButton_2.Text = 'Start';
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = 'MIT.jpg';
            app.Image2 = uiimage(app.GridLayout);
            app.Image2.ImageSource = 'mslg.jpg';
            app.TsSliderLabel = uilabel(app.GridLayout);
            app.TsSliderLabel.Text = 'Ts';
            app.TsSlider = uislider(app.GridLayout);
            app.TsSlider.Limits = [0 10];
            app.TsSlider.ValueChangedFcn = createCallbackFcn(app, @TsSliderValueChanged, true);
            app.faiSliderLabel = uilabel(app.GridLayout);
            app.faiSliderLabel.Text = 'fai';
            app.faiSlider = uislider(app.GridLayout);
            app.faiSlider.Limits = [-1 1];
            app.faiSlider.ValueChangedFcn = createCallbackFcn(app, @faiSliderValueChanged, true);
            app.xsSliderLabel = uilabel(app.GridLayout);
            app.xsSliderLabel.Text = 'xs';
            app.xsSlider = uislider(app.GridLayout);
            app.xsSlider.Limits = [-50 50];
            app.xsSlider.ValueChangedFcn = createCallbackFcn(app, @xsSliderValueChanged, true);
            app.xfSliderLabel = uilabel(app.GridLayout);
            app.xfSliderLabel.Text = 'xf';
            app.xfSlider = uislider(app.GridLayout);
            app.xfSlider.Limits = [-50 50];
            app.xfSlider.ValueChangedFcn = createCallbackFcn(app, @xfSliderValueChanged, true);
            app.hSliderLabel = uilabel(app.GridLayout);
            app.hSliderLabel.Text = 'h';
            app.hSlider = uislider(app.GridLayout);
            app.hSlider.ValueChangedFcn = createCallbackFcn(app, @hSliderValueChanged, true);
            app.zsSliderLabel = uilabel(app.GridLayout);
            app.zsSliderLabel.Text = 'zs';
            app.zsSlider = uislider(app.GridLayout);
            app.zsSlider.Limits = [0 20];
            app.zsSlider.ValueChangedFcn = createCallbackFcn(app, @zsSliderValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = zhengni(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end